classdef WheelingProfileVisual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        InputparametersTab              matlab.ui.container.Tab
        ToolpathparametersPanel         matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        mmLabel_56                      matlab.ui.control.Label
        FixedwheelingboundaryEditField  matlab.ui.control.NumericEditField
        FixedwheelingboundaryEditFieldLabel  matlab.ui.control.Label
        mmLabel_55                      matlab.ui.control.Label
        DrivethroughdistanceLabel       matlab.ui.control.Label
        DrivethroughdistanceEditField   matlab.ui.control.NumericEditField
        mmLabel_30                      matlab.ui.control.Label
        mmLabel_29                      matlab.ui.control.Label
        TrackingspacingEditField        matlab.ui.control.NumericEditField
        TrackingspacingEditFieldLabel   matlab.ui.control.Label
        EndeffectorboundaryEditField    matlab.ui.control.NumericEditField
        EndeffectorboundaryEditFieldLabel  matlab.ui.control.Label
        ProcessingparametersPanel       matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        DiscreteCheckBox                matlab.ui.control.CheckBox
        ContinuousCheckBox              matlab.ui.control.CheckBox
        mmLabel_57                      matlab.ui.control.Label
        ResolutionEditFieldLabel        matlab.ui.control.Label
        ResolutionEditField             matlab.ui.control.NumericEditField
        MachineparametersPanel          matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        mmsLabel_2                      matlab.ui.control.Label
        mmsLabel                        matlab.ui.control.Label
        mmLabel_9                       matlab.ui.control.Label
        ArcspeedEditField               matlab.ui.control.NumericEditField
        ArcspeedEditFieldLabel          matlab.ui.control.Label
        TooltypeDropDown                matlab.ui.control.DropDown
        TooltypeDropDownLabel           matlab.ui.control.Label
        FeedrateEditField               matlab.ui.control.NumericEditField
        FeedrateEditFieldLabel          matlab.ui.control.Label
        RollgapEditField                matlab.ui.control.NumericEditField
        RollgapEditFieldLabel           matlab.ui.control.Label
        SheetparametersPanel            matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        mmLabel_16                      matlab.ui.control.Label
        MaterialEditFieldLabel          matlab.ui.control.Label
        MaterialEditField               matlab.ui.control.EditField
        SheetsizeEditFieldLabel         matlab.ui.control.Label
        SheetsizeEditField              matlab.ui.control.NumericEditField
        GenerateprofileTab              matlab.ui.container.Tab
        ExportdataPanel                 matlab.ui.container.Panel
        NameoffileEditField             matlab.ui.control.EditField
        NameoffileEditFieldLabel        matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        ExportoptionsDropDown           matlab.ui.control.DropDown
        ExportoptionsDropDownLabel      matlab.ui.control.Label
        ExportdatapathTextArea          matlab.ui.control.TextArea
        SelectdirectoryLabel            matlab.ui.control.Label
        DirButton                       matlab.ui.control.Button
        SelectstrategyButtonGroup       matlab.ui.container.ButtonGroup
        ClearprofileButton              matlab.ui.control.Button
        CreateprofileButton             matlab.ui.control.Button
        StrategyDropDown                matlab.ui.control.DropDown
        StrategyDropDownLabel           matlab.ui.control.Label
        StrategyButton                  matlab.ui.control.ToggleButton
        IndividualButton                matlab.ui.control.ToggleButton
        TabGroup3                       matlab.ui.container.TabGroup
        ProfileparametersTab            matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        CentretooutsideTab              matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        mmLabel_54                      matlab.ui.control.Label
        mmLabel_53                      matlab.ui.control.Label
        mmLabel_52                      matlab.ui.control.Label
        mmLabel_51                      matlab.ui.control.Label
        C2OscalingfactorEditField       matlab.ui.control.NumericEditField
        ScalingfactorEditFieldLabel     matlab.ui.control.Label
        C2OnumberofpassesEditField      matlab.ui.control.NumericEditField
        NumberofpassesEditField_2Label  matlab.ui.control.Label
        C2OminimumheightEditField       matlab.ui.control.NumericEditField
        MinimumheightEditFieldLabel     matlab.ui.control.Label
        C2OcentralypositionEditField    matlab.ui.control.NumericEditField
        CentralypositionEditFieldLabel  matlab.ui.control.Label
        C2OcentralxpositionEditField    matlab.ui.control.NumericEditField
        CentralxpositionEditFieldLabel  matlab.ui.control.Label
        C2OminimumwidthEditField        matlab.ui.control.NumericEditField
        MinimumwidthEditFieldLabel      matlab.ui.control.Label
        DiagonalTab                     matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        mmLabel_50                      matlab.ui.control.Label
        mmLabel_49                      matlab.ui.control.Label
        mmLabel_48                      matlab.ui.control.Label
        mmLabel_47                      matlab.ui.control.Label
        StartypositionEditFieldLabel    matlab.ui.control.Label
        DiagstartypositionEditField     matlab.ui.control.NumericEditField
        StartxpositionEditFieldLabel    matlab.ui.control.Label
        DiagstartxpositionEditField     matlab.ui.control.NumericEditField
        NumberofpassesEditField_3Label  matlab.ui.control.Label
        DiagnumberofpassesEditField     matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        DiagheightEditField             matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        DiagwidthEditField              matlab.ui.control.NumericEditField
        VerticalexpandTab               matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        mmLabel_46                      matlab.ui.control.Label
        mmLabel_45                      matlab.ui.control.Label
        CentralxpositionEditField_2Label  matlab.ui.control.Label
        VertcentralxpositionEditField   matlab.ui.control.NumericEditField
        MinimumwidthEditField_2Label    matlab.ui.control.Label
        VertminimumwidthEditField       matlab.ui.control.NumericEditField
        NumberofpassesEditField_4Label  matlab.ui.control.Label
        VertnumberofpassesEditField     matlab.ui.control.NumericEditField
        ScalingfactorEditField_2Label   matlab.ui.control.Label
        VertscalingfactorEditField      matlab.ui.control.NumericEditField
        HorizontalexpandTab             matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        mmLabel_44                      matlab.ui.control.Label
        mmLabel_43                      matlab.ui.control.Label
        CentralypositionEditField_2Label  matlab.ui.control.Label
        HorcentralypositionEditField    matlab.ui.control.NumericEditField
        MinimumheightEditField_2Label   matlab.ui.control.Label
        HorminimumheightEditField       matlab.ui.control.NumericEditField
        NumberofpassesEditField_5Label  matlab.ui.control.Label
        HornumberofpassesEditField      matlab.ui.control.NumericEditField
        ScalingfactorEditField_3Label   matlab.ui.control.Label
        HorscalingfactorEditField       matlab.ui.control.NumericEditField
        OverlayedTab                    matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        mmLabel_42                      matlab.ui.control.Label
        mmLabel_41                      matlab.ui.control.Label
        mmLabel_40                      matlab.ui.control.Label
        mmLabel_39                      matlab.ui.control.Label
        NumberofpassesEditField_2Label_2  matlab.ui.control.Label
        OverlaynumberofpassesEditField  matlab.ui.control.NumericEditField
        CentralypositionEditFieldLabel_2  matlab.ui.control.Label
        OverlaycentralypositionEditField  matlab.ui.control.NumericEditField
        CentralxpositionEditFieldLabel_2  matlab.ui.control.Label
        OverlaycentralxpositionEditField  matlab.ui.control.NumericEditField
        HeightEditFieldLabel_2          matlab.ui.control.Label
        OverlayheightEditField          matlab.ui.control.NumericEditField
        WidthEditFieldLabel_2           matlab.ui.control.Label
        OverlaywidthEditField           matlab.ui.control.NumericEditField
        TrackverticalTab                matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        mmLabel_38                      matlab.ui.control.Label
        mmLabel_37                      matlab.ui.control.Label
        NumberofpassesEditField_3Label_2  matlab.ui.control.Label
        TrackvertnumberofpassesEditField  matlab.ui.control.NumericEditField
        MaximumheightEditFieldLabel     matlab.ui.control.Label
        TrackvertmaximumheightEditField  matlab.ui.control.NumericEditField
        MinimumheightEditFieldLabel_2   matlab.ui.control.Label
        TrackvertminimumheightEditField  matlab.ui.control.NumericEditField
        TrackhorizontalTab              matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        mmLabel_36                      matlab.ui.control.Label
        mmLabel_35                      matlab.ui.control.Label
        NumberofpassesEditField_4Label_2  matlab.ui.control.Label
        TrackhornumberofpassesEditField  matlab.ui.control.NumericEditField
        MaximumwidthEditFieldLabel      matlab.ui.control.Label
        TrackhormaximumwidthEditField   matlab.ui.control.NumericEditField
        MinimumwidthEditFieldLabel_2    matlab.ui.control.Label
        TrackhorminimumwidthEditField   matlab.ui.control.NumericEditField
        ConvergingTab                   matlab.ui.container.Tab
        TriangleTab                     matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        mmLabel_34                      matlab.ui.control.Label
        mmLabel_33                      matlab.ui.control.Label
        mmLabel_32                      matlab.ui.control.Label
        mmLabel_31                      matlab.ui.control.Label
        NumberofpassesEditField_3Label_3  matlab.ui.control.Label
        TrinumberofpassesEditField      matlab.ui.control.NumericEditField
        BasecentreypositionEditFieldLabel  matlab.ui.control.Label
        TribasecentreypositionEditField  matlab.ui.control.NumericEditField
        BasecentrexpositionEditFieldLabel  matlab.ui.control.Label
        TribasecentrexpositionEditField  matlab.ui.control.NumericEditField
        HeightEditFieldLabel_3          matlab.ui.control.Label
        TriheightEditField              matlab.ui.control.NumericEditField
        BasewidthEditFieldLabel         matlab.ui.control.Label
        TribasewidthEditField           matlab.ui.control.NumericEditField
        TrackingdensityTab              matlab.ui.container.Tab
        ShowborderCheckBox_2            matlab.ui.control.CheckBox
        AxesoffCheckBox                 matlab.ui.control.CheckBox
        UIAxes5                         matlab.ui.control.UIAxes
        ToolpathTab                     matlab.ui.container.Tab
        ShownodesCheckBox               matlab.ui.control.CheckBox
        ShowborderCheckBox              matlab.ui.control.CheckBox
        AxesoffCheckBox_2               matlab.ui.control.CheckBox
        ClearButton_2                   matlab.ui.control.Button
        PassnoSpinner                   matlab.ui.control.Spinner
        PassnoSpinnerLabel              matlab.ui.control.Label
        GenerateButton_2                matlab.ui.control.Button
        StopButton_2                    matlab.ui.control.Button
        StartanimationButton            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ProcesssimulationTab            matlab.ui.container.Tab
        FeedratemmsTextArea             matlab.ui.control.TextArea
        FeedratemmsTextAreaLabel        matlab.ui.control.Label
        AxespositionsLabel              matlab.ui.control.Label
        AxesUITable                     matlab.ui.control.Table
        SimulationparametersPanel       matlab.ui.container.Panel
        PassnumberSpinner               matlab.ui.control.Spinner
        PassnumberSpinnerLabel          matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        PausePlayButton                 matlab.ui.control.Button
        StartsimulationButton           matlab.ui.control.Button
        CommandsPanel                   matlab.ui.container.Panel
        GcodecommandTextArea            matlab.ui.control.TextArea
        GcodecommandTextAreaLabel       matlab.ui.control.Label
        OperationnameTextArea           matlab.ui.control.TextArea
        OperationnameTextAreaLabel      matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        SamplegenerationTab             matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        TabGroup8                       matlab.ui.container.TabGroup
        GenerateTab                     matlab.ui.container.Tab
        SamplenumberSpinner             matlab.ui.control.Spinner
        SamplenumberSpinnerLabel        matlab.ui.control.Label
        VisualiseButton                 matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        GeneratesamplesButton           matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        ExportButton_2                  matlab.ui.control.Button
        ExportoptionsDropDown_2         matlab.ui.control.DropDown
        ExportoptionsDropDown_2Label    matlab.ui.control.Label
        NameoffileEditField_2           matlab.ui.control.EditField
        NameoffileEditField_2Label      matlab.ui.control.Label
        ExportdatapathTextArea_2        matlab.ui.control.TextArea
        SelectdirectoryLabel_2          matlab.ui.control.Label
        DirButton_2                     matlab.ui.control.Button
        VisualisePanel                  matlab.ui.container.Panel
        SamplevisualiseUIAxes           matlab.ui.control.UIAxes
        DataPanel                       matlab.ui.container.Panel
        TabGroup6                       matlab.ui.container.TabGroup
        SamplespaceTab                  matlab.ui.container.Tab
        SamplespaceUITable              matlab.ui.control.Table
        SelectionTab                    matlab.ui.container.Tab
        SampleselectionUITable          matlab.ui.control.Table
        AreavariationTab                matlab.ui.container.Tab
        GenerateButton                  matlab.ui.control.Button
        AreaUIAxes                      matlab.ui.control.UIAxes
        ParametervariationTab           matlab.ui.container.Tab
        DisplayButton                   matlab.ui.control.Button
        SelectparameterDropDown         matlab.ui.control.DropDown
        SelectparameterDropDownLabel    matlab.ui.control.Label
        ParamUIAxes                     matlab.ui.control.UIAxes
        SamplingparametersPanel         matlab.ui.container.Panel
        TabGroup7                       matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        MinimumtrackedareaEditField     matlab.ui.control.NumericEditField
        MinimumtrackedareaEditFieldLabel  matlab.ui.control.Label
        SamplingdiscreteCheckBox        matlab.ui.control.CheckBox
        SamplingcontinuousCheckBox      matlab.ui.control.CheckBox
        ResolutionEditField_2Label      matlab.ui.control.Label
        SamplingresolutionEditField     matlab.ui.control.NumericEditField
        DimensionmagnitudeLabel         matlab.ui.control.Label
        DimensionmagnitudeEditField     matlab.ui.control.NumericEditField
        StrategyDropDown_2Label         matlab.ui.control.Label
        SamplingstrategyDropDown        matlab.ui.control.DropDown
        NumberofsamplesEditFieldLabel   matlab.ui.control.Label
        NumberofsamplesEditField        matlab.ui.control.NumericEditField
        StrategyTab                     matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        CentretooutsideTab_2            matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        MaximumscalingEditFieldLabel    matlab.ui.control.Label
        MaximumscalingEditField         matlab.ui.control.NumericEditField
        MinimumscalingEditFieldLabel    matlab.ui.control.Label
        MinimumscalingEditField         matlab.ui.control.NumericEditField
        MaximumypositionEditFieldLabel  matlab.ui.control.Label
        MaximumypositionEditField       matlab.ui.control.NumericEditField
        MinimumypositionEditFieldLabel  matlab.ui.control.Label
        MinimumypositionEditField       matlab.ui.control.NumericEditField
        MaximumxpositionEditFieldLabel  matlab.ui.control.Label
        MaximumxpositionEditField       matlab.ui.control.NumericEditField
        MinimumxpositionEditFieldLabel  matlab.ui.control.Label
        MinimumxpositionEditField       matlab.ui.control.NumericEditField
        MaximumheightEditFieldLabel_2   matlab.ui.control.Label
        MaximumheightEditField          matlab.ui.control.NumericEditField
        MinimumheightEditFieldLabel_3   matlab.ui.control.Label
        MinimumheightEditField          matlab.ui.control.NumericEditField
        MaximumwidthEditFieldLabel_2    matlab.ui.control.Label
        MaximumwidthEditField           matlab.ui.control.NumericEditField
        MinimumwidthLabel               matlab.ui.control.Label
        MinimumwidthEditField           matlab.ui.control.NumericEditField
        DiagonalTab_2                   matlab.ui.container.Tab
        VerticalexpansionTab            matlab.ui.container.Tab
        MaximumscalingEditField_2       matlab.ui.control.NumericEditField
        MaximumscalingEditField_2Label  matlab.ui.control.Label
        MinimumscalingEditField_2       matlab.ui.control.NumericEditField
        MinimumscalingEditField_2Label  matlab.ui.control.Label
        MaximumxpositionEditField_2     matlab.ui.control.NumericEditField
        MaximumxpositionEditField_2Label  matlab.ui.control.Label
        MinimumxpositionEditField_2     matlab.ui.control.NumericEditField
        MinimumxpositionEditField_2Label  matlab.ui.control.Label
        MaximumwidthEditField_2         matlab.ui.control.NumericEditField
        MaximumwidthEditField_2Label    matlab.ui.control.Label
        MinimumwidthEditField_2         matlab.ui.control.NumericEditField
        MinimumwidthEditField_2Label_2  matlab.ui.control.Label
        HorizontalexpansionTab          matlab.ui.container.Tab
        MaximumscalingEditField_3       matlab.ui.control.NumericEditField
        MaximumscalingEditField_3Label  matlab.ui.control.Label
        MinimumscalingEditField_3       matlab.ui.control.NumericEditField
        MinimumscalingEditField_3Label  matlab.ui.control.Label
        MaximumypositionEditField_2     matlab.ui.control.NumericEditField
        MaximumypositionEditField_2Label  matlab.ui.control.Label
        MinimumypositionEditField_2     matlab.ui.control.NumericEditField
        MinimumypositionEditField_2Label  matlab.ui.control.Label
        MaximumheightEditField_2        matlab.ui.control.NumericEditField
        MaximumheightEditField_2Label   matlab.ui.control.Label
        MinimumheightEditField_2        matlab.ui.control.NumericEditField
        MinimumheightEditField_2Label_2  matlab.ui.control.Label
        OverlayedTab_2                  matlab.ui.container.Tab
        MinimumwidthEditField_3         matlab.ui.control.NumericEditField
        MinimumwidthEditField_3Label    matlab.ui.control.Label
        MaximumheightEditField_3        matlab.ui.control.NumericEditField
        MaximumheightEditField_3Label   matlab.ui.control.Label
        MinimumheightEditField_3        matlab.ui.control.NumericEditField
        MinimumheightEditField_3Label   matlab.ui.control.Label
        MaximumypositionEditField_3     matlab.ui.control.NumericEditField
        MaximumypositionEditField_3Label  matlab.ui.control.Label
        MinimumypositionEditField_3     matlab.ui.control.NumericEditField
        MinimumypositionEditField_3Label  matlab.ui.control.Label
        MaximumxpositionEditField_3     matlab.ui.control.NumericEditField
        MaximumxpositionEditField_3Label  matlab.ui.control.Label
        MinimumxpositionEditField_3     matlab.ui.control.NumericEditField
        MinimumxpositionEditField_3Label  matlab.ui.control.Label
        MaximumwidthEditField_3         matlab.ui.control.NumericEditField
        MaximumwidthEditField_3Label    matlab.ui.control.Label
        TriangularTab                   matlab.ui.container.Tab
        MaximumheightEditField_4        matlab.ui.control.NumericEditField
        MaximumheightEditField_4Label   matlab.ui.control.Label
        MinimumheightEditField_4        matlab.ui.control.NumericEditField
        MinimumheightEditField_4Label   matlab.ui.control.Label
        MaximumypositionEditField_4     matlab.ui.control.NumericEditField
        MaximumypositionEditField_4Label  matlab.ui.control.Label
        MinimumypositionEditField_4     matlab.ui.control.NumericEditField
        MinimumypositionEditField_4Label  matlab.ui.control.Label
        MaximumxpositionEditField_4     matlab.ui.control.NumericEditField
        MaximumxpositionEditField_4Label  matlab.ui.control.Label
        MinimumxpositionEditField_4     matlab.ui.control.NumericEditField
        MinimumxpositionEditField_4Label  matlab.ui.control.Label
        MaximumbasewidthEditField       matlab.ui.control.NumericEditField
        MaximumbasewidthEditFieldLabel  matlab.ui.control.Label
        MinimumbasewidthEditField       matlab.ui.control.NumericEditField
        MinimumbasewidthEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        WheelingProfileData % Wheeling coordinates
        Params % Parameter structure
        MS % Manufacturing strategy structure
        Strats % Manufacturing strategies structure
        PauseSimulation % Pauses simulation
        StopSimulation % Stops simulation
        Heatmap % Description
        WheelDist = 115; % D
        CentrePos = 68; % Description
        StopAnimation % Description
        DiscreteDist % Description
        ExportPath % Description
        PathAxes % Description
        SampleSet 
        AreaData % Description
        SampleRawData % Description
        BoxplotData % Description
        SamplingExportPath % Description
        TPBorder % Description
        TPScatter % Description
        DBorder % Description
    end
    
    methods (Access = private)
        
        % Saves user-defined parameter data
        function[] = GenerateData(app,Spacing,SheetSize, ...
                EffBound,DriveDist,WheelBound,RollGap,FeedRate,Material, ...
                ToolType,Strategy,C2OMinWidth,C2OMinHeight,C2ONumPasses, ...
                C2OCentrex,C2OCentrey,C2OScalingFactor,DiagonalWidth, ...
                DiagonalHeight,DiagonalNumPasses,DiagonalStartx,...
                DiagonalStarty,VertScalingFactor,VertNumPasses, ...
                VertMinWidth,VertCentrex,HorScalingFactor, ...
                HorNumPasses,HorMinHeight,HorCentrey,OverNumPasses, ...
                OverCentrey,OverCentrex,OverHeight,OverWidth, ...
                TrackVertMinHeight,TrackVertMaxHeight, ...
                TrackVertNumPasses,TrackHorMinWidth, ...
                TrackHorMaxWidth,TrackHorNumPasses,TriBaseWidth, ...
                TriHeight,TriBaseCentrex,TriBaseCentrey,TriNumPasses, ...
                Resolution,DiscreteCheck)
            
            % Sheet parameters
            app.Params.Sheet.SheetSize = SheetSize;
            app.Params.Sheet.Material = Material;
            
            % Machine parameters
            app.Params.Machine.FeedRate = FeedRate;
            app.Params.Machine.RollGap = RollGap;
            app.Params.Machine.ToolType = ToolType;
            
            % Tool path parameters
            app.Params.Path.EffBound = EffBound;
            app.Params.Path.EffBoundNorm = EffBound/SheetSize;
            app.Params.Path.Spacing = Spacing;
            app.Params.Path.SpacingNorm = Spacing/SheetSize;
            app.Params.Path.DriveDist = DriveDist;
            app.Params.Path.DriveDistNorm = DriveDist/SheetSize;
            app.Params.Path.WheelBound = WheelBound;
            app.Params.Path.WheelBoundNorm = WheelBound/SheetSize;
            
            % Fixed parameters
            app.Params.Fixed.WheelDist = app.WheelDist;
            app.Params.Fixed.WheelDistNorm = app.WheelDist/SheetSize;
            app.Params.Fixed.xlim = app.CentrePos;
            app.Params.Fixed.xlimNorm = app.CentrePos/SheetSize;
            
            % Processing parameters
            app.Params.Process.Resolution = Resolution;
            app.Params.Process.ResolutionNorm = Resolution/SheetSize;
            app.Params.Process.Discrete = DiscreteCheck;
            
            % Strategy parameters
            app.Params.Strategy.Name = Strategy;
            
            switch Strategy 
                case 'Centre to outside'
                    app.Params.Strategy.Value.MinWidth = C2OMinWidth;
                    app.Params.Strategy.Value.MinHeight = C2OMinHeight;
                    app.Params.Strategy.Value.NumPasses = C2ONumPasses;
                    app.Params.Strategy.Value.Centrex = C2OCentrex;
                    app.Params.Strategy.Value.Centrey = C2OCentrey;
                    app.Params.Strategy.Value.ScalingFactor = C2OScalingFactor;
                    app.Params.Strategy.Norm.MinWidth = C2OMinWidth/SheetSize;
                    app.Params.Strategy.Norm.MinHeight = C2OMinHeight/SheetSize;
                    app.Params.Strategy.Norm.Centrex = C2OCentrex/SheetSize;
                    app.Params.Strategy.Norm.Centrey = C2OCentrey/SheetSize;
                case 'Diagonal'
                    app.Params.Strategy.Value.Width = DiagonalWidth;
                    app.Params.Strategy.Value.Height = DiagonalHeight;
                    app.Params.Strategy.Value.NumPasses = DiagonalNumPasses;
                    app.Params.Strategy.Value.Startx = DiagonalStartx;
                    app.Params.Strategy.Value.Starty = DiagonalStarty;
                    app.Params.Strategy.Norm.Width = DiagonalWidth/SheetSize;
                    app.Params.Strategy.Norm.Height = DiagonalHeight/SheetSize;
                    app.Params.Strategy.Norm.Startx = DiagonalStartx/SheetSize;
                    app.Params.Strategy.Norm.Starty = DiagonalStarty/SheetSize;
                case 'Vertical expand'
                    app.Params.Strategy.Value.ScalingFactor = VertScalingFactor;
                    app.Params.Strategy.Value.NumPasses = VertNumPasses;
                    app.Params.Strategy.Value.MinWidth = VertMinWidth;
                    app.Params.Strategy.Value.Centrex = VertCentrex;
                    app.Params.Strategy.Norm.MinWidth = VertMinWidth/SheetSize;
                    app.Params.Strategy.Norm.Centrex = VertCentrex/SheetSize;
                case 'Horizontal expand'
                    app.Params.Strategy.Value.ScalingFactor = HorScalingFactor;
                    app.Params.Strategy.Value.NumPasses = HorNumPasses;
                    app.Params.Strategy.Value.MinHeight = HorMinHeight;
                    app.Params.Strategy.Value.Centrey = HorCentrey;
                    app.Params.Strategy.Norm.MinHeight = HorMinHeight/SheetSize;
                    app.Params.Strategy.Norm.Centrey = HorCentrey/SheetSize;
                    
                case 'Triangle'
                    app.Params.Strategy.Value.BaseWidth = TriBaseWidth;
                    app.Params.Strategy.Value.Height = TriHeight;
                    app.Params.Strategy.Value.BaseCentrex = TriBaseCentrex;
                    app.Params.Strategy.Value.BaseCentrey = TriBaseCentrey;
                    app.Params.Strategy.Value.NumPasses = TriNumPasses;
                    app.Params.Strategy.Norm.BaseWidth = TriBaseWidth/SheetSize;
                    app.Params.Strategy.Norm.Height = TriHeight/SheetSize;
                    app.Params.Strategy.Norm.BaseCentrex = TriBaseCentrex/SheetSize;
                    app.Params.Strategy.Norm.BaseCentrey = TriBaseCentrey/SheetSize;
                    
                case 'Overlayed'
                    app.Params.Strategy.Value.Width = OverWidth;
                    app.Params.Strategy.Value.Height = OverHeight;
                    app.Params.Strategy.Value.NumPasses = OverNumPasses;
                    app.Params.Strategy.Value.Centrex = OverCentrex;
                    app.Params.Strategy.Value.Centrey = OverCentrey;
                    app.Params.Strategy.Norm.Width = OverWidth/SheetSize;
                    app.Params.Strategy.Norm.Height = OverHeight/SheetSize;
                    app.Params.Strategy.Norm.Centrex = OverCentrex/SheetSize;
                    app.Params.Strategy.Norm.Centrey = OverCentrey/SheetSize;
                    
                case 'Track vertical'
                    app.Params.Strategy.Value.MinHeight = TrackVertMinHeight;
                    app.Params.Strategy.Value.MaxHeight = TrackVertMaxHeight;
                    app.Params.Strategy.Value.NumPasses = TrackVertNumPasses;
                    app.Params.Strategy.Norm.MinHeight = TrackVertMinHeight/SheetSize;
                    app.Params.Strategy.Norm.MaxHeight = TrackVertMaxHeight/SheetSize;
                    
                case 'Track horizontal'
                    app.Params.Strategy.Value.MinWidth = TrackHorMinWidth;
                    app.Params.Strategy.Value.MaxWidth = TrackHorMaxWidth;
                    app.Params.Strategy.Value.NumPasses = TrackHorNumPasses;
                    app.Params.Strategy.Norm.MinWidth = TrackHorMinWidth/SheetSize;
                    app.Params.Strategy.Norm.MaxWidth = TrackHorMaxWidth/SheetSize;
                    
                case 'Converging'
                    
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateprofileButton
        function CreateprofileButtonPushed(app, event)
            
            %% Create strategy and display tracking density %%
            
            % Save user inputs
            GenerateData(app, ...
                app.TrackingspacingEditField.Value, ...
                app.SheetsizeEditField.Value, ...
                app.EndeffectorboundaryEditField.Value, ...
                app.DrivethroughdistanceEditField.Value, ...
                app.FixedwheelingboundaryEditField.Value, ...
                app.RollgapEditField.Value, ...
                app.FeedrateEditField.Value, ...
                app.MaterialEditField.Value, ...
                app.TooltypeDropDown.Value, ...
                app.StrategyDropDown.Value, ...
                app.C2OminimumwidthEditField.Value, ...
                app.C2OminimumheightEditField.Value, ...
                app.C2OnumberofpassesEditField.Value, ...
                app.C2OcentralxpositionEditField.Value, ...
                app.C2OcentralypositionEditField.Value, ...
                app.C2OscalingfactorEditField.Value, ...
                app.DiagwidthEditField.Value, ...
                app.DiagheightEditField.Value, ...
                app.DiagnumberofpassesEditField.Value, ...
                app.DiagstartxpositionEditField.Value, ...
                app.DiagstartypositionEditField.Value, ...
                app.VertscalingfactorEditField.Value, ...
                app.VertnumberofpassesEditField.Value, ...
                app.VertminimumwidthEditField.Value, ...
                app.VertcentralxpositionEditField.Value, ...
                app.HorscalingfactorEditField.Value, ...
                app.HornumberofpassesEditField.Value, ...
                app.HorminimumheightEditField.Value, ...
                app.HorcentralypositionEditField.Value, ...
                app.OverlaynumberofpassesEditField.Value, ...
                app.OverlaycentralypositionEditField.Value, ...
                app.OverlaycentralxpositionEditField.Value, ...
                app.OverlayheightEditField.Value, ...
                app.OverlaywidthEditField.Value, ...
                app.TrackvertminimumheightEditField.Value, ...
                app.TrackvertmaximumheightEditField.Value, ...
                app.TrackvertnumberofpassesEditField.Value, ...
                app.TrackhorminimumwidthEditField.Value, ...
                app.TrackhormaximumwidthEditField.Value, ...
                app.TrackhornumberofpassesEditField.Value, ...
                app.TribasewidthEditField.Value, ...
                app.TriheightEditField.Value, ...
                app.TribasecentrexpositionEditField.Value, ...
                app.TribasecentreypositionEditField.Value, ...
                app.TrinumberofpassesEditField.Value, ...
                app.ResolutionEditField.Value, ...
                app.DiscreteCheckBox.Value);
            
            if app.Params.Process.Discrete
                % Number of cells for density estimation
                NumCells = 1/app.Params.Process.ResolutionNorm;
            else
                NumCells = 1/app.Params.Path.SpacingNorm;
            end
            
            % Initialise density estimation parameter
            dist = [];
            app.DiscreteDist = {};
            
            % Calculate strategies for selected parameters
            for i = 1:app.Params.Strategy.Value.NumPasses
                
                % Determine intermediate nodes at transfer points
                app.MS = AddNodes(app.Params,i);
                app.MS.setup = app.Params;
            
                % Calculate kinematics of the machine axes
                app.MS = CalculateKinematics(app.MS,app.Params);
                
                % Add to density estimation parameter
                dist = [dist; app.MS.DiscretePath];
                app.DiscreteDist{i} = dist;
                
                % Collect strategy
                app.Strats{i} = app.MS;
            
            end
            
            % Initialise axis
            axis(app.UIAxes5,[0 1 0 1])
            pbaspect(app.UIAxes5,[1 1 1])
            hold(app.UIAxes5,'on');
            
            xlabel(app.UIAxes5,'X')
            ylabel(app.UIAxes5,'Y')
            
            % Discretise if required
            if app.Params.Process.Discrete
                
                grid(app.UIAxes5,'minor')
                grid(app.UIAxes5,'on')
                
                app.UIAxes5.XAxis.MinorTick = 'on';
                app.UIAxes5.XAxis.MinorTickValues = 0:0.02:1;
                
                app.UIAxes5.YAxis.MinorTick = 'on';
                app.UIAxes5.YAxis.MinorTickValues = 0:0.02:1;
                
                app.UIAxes5.XTick = 0:0.2:1;
                app.UIAxes5.YTick = 0:0.2:1;
            else
                grid(app.UIAxes5,'off')
                set(app.UIAxes5,'xtick',[0:0.2:1])
                set(app.UIAxes5,'ytick',[0:0.2:1])
            end
            
            % Plot tracking density
            for j = 1:length(app.Strats)
                fill(app.UIAxes5,app.Strats{j}.AreaCorners(:,1),app.Strats{j}.AreaCorners(:,2)','r','FaceAlpha',0.1,'LineStyle','none')
            end
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            %% Start simulation %%
            
            % Select simulation data from user parameter
            SimData = app.Strats{app.PassnumberSpinner.Value};

            % Calculate coordinates of sheet edges
            SheetCoords = GenerateSheetCoords(SimData);
            
            % Initialise axis
            axis(app.UIAxes2,[-1 1 -1 1])
            axis(app.UIAxes6,[0 1 0 1])
            
            % Initialise end effector position arrays
            end1_xstart = SimData.node(1).effectors.position{1,1}(1);
            end1_ystart = SimData.node(1).effectors.position{1,1}(2);
            end2_xstart = SimData.node(1).effectors.position{1,2}(1);
            end2_ystart = SimData.node(1).effectors.position{1,2}(2);
            
            x1 = {};
            y1 = {};
            x2 = {};
            y2 = {};
            
            % For identifying enabled end effector
            enabled = {[0 0]};
            
            % To display axes table
            axes = {};
            
            % To display tool path subplot
            DatumPosx = {};
            DatumPosy = {};
            
            k = 1;
            
            % Data gathering for each node
            for j = 1:length(SimData.node)-1
                
                % Collect axes data
                axes = [axes,SimData.node(j).effectors.axes{1:end-1}];
   
                [nr,~] = size(SimData.node(j).effectors.action);
                
                % Loop through actions for this node
                for i = 1:nr
                    
                    % Determine what nodes are enabled for each action
                    [end1size,~] = size(SimData.node(j).effectors.action{i,1});
                    endsize = size(SimData.node(j).effectors.action);
                    if endsize(2) > 1
                        [end2size,~] = size(SimData.node(j).effectors.action{i,2});
                    else
                        end2size = 0;
                    end
                    
                    % Front effector enabled
                    if end1size
                        % Determine enabled effectors for current action
                        if k > 1
                            if SimData.node(j).effectors.action{i,1}.enabled
                                enabled{k} = [1 0];
                            elseif enabled{k-1}(1) == 0 && enabled{k-1}(2) == 0
                                enabled{k} = [0 0];
                            else
                                enabled{k} = [0 1];
                            end
                        end
                        
                        % Collect data for toolpath subplot
                        if SimData.node(j).effectors.action{i,1}.enabled && strcmp(SimData.node(j).effectors.action{i,1}.name,'translation')
                            DatumPosx{k} = linspace(SimData.node(j).datum.global.position(1),SimData.node(j+1).datum.global.position(1),20);
                            DatumPosy{k} = linspace(SimData.node(j).datum.global.position(2),SimData.node(j+1).datum.global.position(2),20);
                        else
                            DatumPosx{k} = ones(1,20) * SimData.node(j).datum.global.position(1);
                            DatumPosy{k} = ones(1,20) * SimData.node(j).datum.global.position(2);
                        end
                        
                        % Build end effector display data
                        if strcmp(SimData.node(j).effectors.action{i,1}.name,'pivot')
                            end1_pts = SimData.node(j).effectors.action{i,1}.position{2};
                            end1_xnext = end1_pts(1);
                            end1_ynext = end1_pts(2);
                            
                            x1{k} = linspace(end1_xstart,end1_xnext,20);
                            y1{k} = ones(1,20)*end1_ynext;
                        elseif strcmp(SimData.node(j).effectors.action{i,1}.name,'translation')
                            end1_pts = SimData.node(j).effectors.action{i,1}.position{2};
                            end1_xnext = end1_pts(1);
                            end1_ynext = end1_pts(2);
                            
                            grad = SimData.node(j).effectors.action{i,1}.O(2)/SimData.node(j).effectors.action{i,1}.O(1);
                            
                            if abs(grad) > 100000
                                x1{k} = ones(1,20)*end1_xnext;
                                y1{k} = linspace(end1_ystart,end1_ynext,20);
                            elseif abs(grad) < 0.001
                                y1{k} = ones(1,20)*end1_xnext;
                                x1{k} = linspace(end1_ystart,end1_ynext,20);
                            else
                                c = end1_ynext - grad*end1_xnext;
                                xpts = linspace(end1_xstart,end1_xnext,20);
                                ypts = xpts*grad + c;
                                x1{k} = xpts;
                                y1{k} = ypts;
                            end
                        else
                            end1_xnext = end1_xstart;
                            end1_ynext = end1_ystart;
                        
                            x1{k} = ones(1,20) * end1_xstart;
                            y1{k} = ones(1,20) * end1_ystart;
                            
                        end
                    else
                        end1_xnext = end1_xstart;
                        end1_ynext = end1_ystart;
                        
                        x1{k} = ones(1,20) * end1_xstart;
                        y1{k} = ones(1,20) * end1_ystart;
                    end
                    
                    if end2size
                        if k > 1
                            if SimData.node(j).effectors.action{i,2}.enabled
                                enabled{k} = [0 1];
                            elseif enabled{k-1}(1) == 0 && enabled{k-1}(2) == 0
                                enabled{k} = [0 0];
                            else
                                enabled{k} = [1 0];
                            end
                        end
                        
                        if SimData.node(j).effectors.action{i,2}.enabled && strcmp(SimData.node(j).effectors.action{i,2}.name,'translation')
                            DatumPosx{k} = linspace(SimData.node(j).datum.global.position(1),SimData.node(j+1).datum.global.position(1),20);
                            DatumPosy{k} = linspace(SimData.node(j).datum.global.position(2),SimData.node(j+1).datum.global.position(2),20);
                        else
                            DatumPosx{k} = ones(1,20) * SimData.node(j).datum.global.position(1);
                            DatumPosy{k} = ones(1,20) * SimData.node(j).datum.global.position(2);
                        end
                        
                        if strcmp(SimData.node(j).effectors.action{i,2}.name,'pivot')
                            end2_pts = SimData.node(j).effectors.action{i,2}.position{2};
                            end2_xnext = end2_pts(1);
                            end2_ynext = end2_pts(2);
                            
                            x2{k} = linspace(end2_xstart,end2_xnext,20);
                            y2{k} = ones(1,20)*end2_ynext;
                        elseif strcmp(SimData.node(j).effectors.action{i,2}.name,'translation')
                            end2_pts = SimData.node(j).effectors.action{i,2}.position{2};
                            end2_xnext = end2_pts(1);
                            end2_ynext = end2_pts(2);
                            
                            grad = SimData.node(j).effectors.action{i,2}.O(2)/SimData.node(j).effectors.action{i,2}.O(1);
                            
                            if abs(grad) > 100000
                                x2{k} = ones(1,20)*end2_xnext;
                                y2{k} = linspace(end2_ystart,end2_ynext,20);
                            elseif abs(grad) < 0.001
                                y2{k} = ones(1,20)*end2_xnext;
                                x2{k} = linspace(end2_ystart,end2_ynext,20);
                            else
                                c = end2_ynext - grad*end2_xnext;
                                xpts = linspace(end2_xstart,end2_xnext,20);
                                ypts = xpts*grad + c;
                                x2{k} = xpts;
                                y2{k} = ypts;
                            end
                        else
                            end2_xnext = end2_xstart;
                            end2_ynext = end2_ystart;
                        
                            x2{k} = ones(1,20) * end2_xstart;
                            y2{k} = ones(1,20) * end2_ystart;
                        end
                    else
                        end2_xnext = end2_xstart;
                        end2_ynext = end2_ystart;
                        
                        x2{k} = ones(1,20) * end2_xstart;
                        y2{k} = ones(1,20) * end2_ystart;
                    end
                    
                    
                    k = k + 1;
                    
                    end1_xstart = end1_xnext;
                    end1_ystart = end1_ynext;
                    end2_xstart = end2_xnext;
                    end2_ystart = end2_ynext;
                    
                    Datumx = cellfun(@(v)v(1),DatumPosx);
                    Datumy = cellfun(@(v)v(1),DatumPosy);
                    
                    p1 = plot(app.UIAxes6,Datumx,Datumy,'r');
                    p1.Color(4) = 0.1;
                end
            end
            
            cla(app.UIAxes2)
            h1 = {};
            h2 = {};
            app.PauseSimulation = 0;
            app.StopSimulation = 0;
            
            AxesNames = 2:8;
            app.AxesUITable.ColumnName = {'Axis';'Start (mm)';'End (mm)'};
            app.AxesUITable.ColumnWidth = {'1x','1x','1x'};
            
            h3 = animatedline(app.UIAxes6,'Color','r');
            
            % Display data
            for n = 1:length(x1)
                
                app.GcodecommandTextArea.Value = SimData.code{n,1};
                app.OperationnameTextArea.Value = SimData.code{n,2};
              
                AxesCells = [cellstr(num2str(AxesNames')),cellstr(num2str(axes{n}(2:end)')),cellstr(num2str(axes{n+1}(2:end)'))];
                
                if strcmp(AxesCells{4,2}(end),'0') && strcmp(AxesCells{4,2}(end-1),' ')
                    string = join([repmat(' ', length(AxesCells{4,2})-2, 1)','AC']);
                    AxesCells{4,2} = string;
                end
                
                if strcmp(AxesCells{4,3}(end),'0') && strcmp(AxesCells{4,3}(end-1),' ')
                    string = join([repmat(' ', length(AxesCells{4,3})-2, 1)','AC']);
                    AxesCells{4,3} = string;
                end
                
                if strcmp(AxesCells{7,2}(end),'0') && strcmp(AxesCells{7,2}(end-1),' ')
                    string = join([repmat(' ', length(AxesCells{7,2})-2, 1)','AC']);
                    AxesCells{7,2} = string;
                end
                
                if strcmp(AxesCells{7,3}(end),'0') && strcmp(AxesCells{7,3}(end-1),' ')
                    string = join([repmat(' ', length(AxesCells{7,3})-2, 1)','AC']);
                    AxesCells{7,3} = string;
                end
                
                app.AxesUITable.Data = AxesCells;
                app.FeedratemmsTextArea.Value = num2str(axes{n+1}(1));
                
                current_sheet = plot(app.UIAxes2,SheetCoords{n}(:,1),SheetCoords{n}(:,2),'k--');
                hold(app.UIAxes2,'on');
                next_sheet = plot(app.UIAxes2,SheetCoords{n+1}(:,1),SheetCoords{n+1}(:,2),'k');
                plot(app.UIAxes2,0,0,'og')
                h1{n} = animatedline(app.UIAxes2,'Color','b');
                h2{n} = animatedline(app.UIAxes2,'Color','r');

                if enabled{n}(1)
                    last_end1 = scatter(app.UIAxes2,x1{1,n-1}(1),y1{1,n-1}(1),'ob');
                    last_end2 = scatter(app.UIAxes2,x2{1,n-1}(1),y2{1,n-1}(1),'xr');
                    current_end1 = scatter(app.UIAxes2,x1{1,n}(1),y1{1,n}(1),'ob');
                    current_end2 = scatter(app.UIAxes2,x2{1,n}(1),y2{1,n}(1),'xr');
                    next_end1 = scatter(app.UIAxes2,x1{1,n+1}(1),y1{1,n+1}(1),'ob');
                    next_end2 = scatter(app.UIAxes2,x2{1,n+1}(1),y2{1,n+1}(1),'xr');
                    
                elseif enabled{n}(2)
                    last_end1 = scatter(app.UIAxes2,x1{1,n-1}(1),y1{1,n-1}(1),'xb');
                    last_end2 = scatter(app.UIAxes2,x2{1,n-1}(1),y2{1,n-1}(1),'or');
                    current_end1 = scatter(app.UIAxes2,x1{1,n}(1),y1{1,n}(1),'xb');
                    current_end2 = scatter(app.UIAxes2,x2{1,n}(1),y2{1,n}(1),'or');
                    next_end1 = scatter(app.UIAxes2,x1{1,n+1}(1),y1{1,n+1}(1),'xb');
                    next_end2 = scatter(app.UIAxes2,x2{1,n+1}(1),y2{1,n+1}(1),'or');
                else 
                    if n > 1
                        last_end1 = scatter(app.UIAxes2,x1{1,n-1}(1),y1{1,n-1}(1),'xb');
                        last_end2 = scatter(app.UIAxes2,x2{1,n-1}(1),y2{1,n-1}(1),'xr');
                    end

                    current_end1 = scatter(app.UIAxes2,x1{1,n}(1),y1{1,n}(1),'xb');
                    current_end2 = scatter(app.UIAxes2,x2{1,n}(1),y2{1,n}(1),'xr');
                    next_end1 = scatter(app.UIAxes2,x1{1,n+1}(1),y1{1,n+1}(1),'xb');
                    next_end2 = scatter(app.UIAxes2,x2{1,n+1}(1),y2{1,n+1}(1),'xr');
                end
 
                for k = 1:length(x1{n})
                    addpoints(h1{n},x1{n}(k),y1{n}(k));
                    addpoints(h2{n},x2{n}(k),y2{n}(k));
                    addpoints(h3,DatumPosx{n}(k),DatumPosy{n}(k))
                    
                    if n > 3
                        clearpoints(h1{n-3});
                        clearpoints(h2{n-3});
                    end
                    drawnow
                end
                
                % Pause animation
                while app.PauseSimulation
                    pause(0.1)
                end
                
                if n > 1
                    delete(last_end1);
                    delete(last_end2);
                end
                
                delete(current_end1);
                delete(next_end1);
                delete(current_end2);
                delete(next_end2);
                delete(next_sheet)
                delete(current_sheet)
                
                % Stop animation
                if app.StopSimulation
                    cla(app.UIAxes2)
                    cla(app.UIAxes6)
                    break
                end
            
            end
        end

        % Callback function
        function GenerateGcodeButtonPushed(app, event)
            [app.MS,End1Code,End2Code] = GenerateGCode(app.MS);
            app.UITable.ColumnName = {'Action 1';'Action 2';'Action 3'};
            
            rownames = {};
            
            for i = 1:length(End1Code)
                rownames{i,1} = append('Node ',num2str(i));
            end
            
            app.UITable.RowName = rownames;
            app.UITable.Data = End1Code;
            
            app.UITable2.ColumnName = {'Action 1';'Action 2';'Action 3'};
            app.UITable2.RowName = rownames;
            app.UITable2.Data = End2Code;

        end

        % Button pushed function: ClearprofileButton
        function ClearprofileButtonPushed(app, event)
            cla(app.UIAxes5,'reset')
            app.MS = [];
            app.Strats = [];
        end

        % Button pushed function: PausePlayButton
        function PausePlayButtonPushed(app, event)
            app.PauseSimulation = ~app.PauseSimulation;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopSimulation = 1;
        end

        % Callback function
        function CleardistributionButtonPushed(app, event)
            cla(app.UIAxes4)
            cla(app.UIAxes5)
            delete(app.Heatmap)
            clear app.Strats;
        end

        % Button pushed function: StartanimationButton
        function StartanimationButtonPushed(app, event)
            %% Starts tracking pattern animation %%
            
            app.StopAnimation = 0;
            
            % Initialise axis
            axis(app.UIAxes,[0 1 0 1])
            hold(app.UIAxes,'on');
            app.PathAxes = cell(length(app.Strats),1);
            
            for j = 1:length(app.Strats)
            
                % Initialise nodal arrays
                profilex = zeros(1,length(app.Strats{j}.node));
                profiley = zeros(1,length(app.Strats{j}.node));
                scatterx = zeros(1,1);
                scattery = zeros(1,1);
                
                % Plot all nodal points
                for i = 1:length(app.Strats{j}.node)
                    scatterx(end+1) = app.Strats{j}.node(i).datum.global.position(1);
                    scattery(end+1) = app.Strats{j}.node(i).datum.global.position(2);
%                     scatter(app.UIAxes,app.Strats{j}.node(i).datum.global.position(1),app.Strats{j}.node(i).datum.global.position(2),'xb');
                    profilex(i) = app.Strats{j}.node(i).datum.global.position(1);
                    profiley(i) = app.Strats{j}.node(i).datum.global.position(2);
                end
                
                % Plot toolpath
                plot(app.UIAxes,profilex,profiley,'r')
                f = figure('visible','off');
                app.PathAxes{j} = copyobj(app.UIAxes,f);
                scatter(app.UIAxes,scatterx(2:end),scattery(2:end),'xb');

                pause(1)
                cla(app.UIAxes)
                
                if app.StopAnimation
                    cla(app.UIAxes)
                    break
                end
            end
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            app.StopAnimation = 1;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %% Export generated strategy data %%
            
            switch app.ExportoptionsDropDown.Value
                case 'Strategies'
                    for i = 1:length(app.Strats)
                        writestruct(app.Strats{i}.setup,append(app.ExportPath,'\Strategy',num2str(i),'.xml'));
                    end
                case 'G-code'
                    for i = 1:length(app.Strats)
                        writecell(app.Strats{i}.code,append(app.ExportPath,'\code',num2str(i),'.txt'));
                    end
                case 'Tracking density'
                    for i = 1:length(app.DiscreteDist)
                        ExportItem = app.DiscreteDist{i};
                        writematrix(ExportItem,append(app.ExportPath,'\',app.NameoffileEditField.Value,'_tracking_dist_',num2str(i),'.csv'));
                    end
                case 'Tracking density plot'
                    Fig = figure;
                    ax = copyobj(app.UIAxes5, Fig);
                    set(ax, 'Units', 'Normalized'); % First change to normalized units.
                    set(ax, 'OuterPosition', [.1, .1, .85, .85]);
                    %hgsave(Fig,append(app.SamplingExportPath,'\',app.NameoffileEditField_2.Value,'_tracking_density_plot.fig'));
                    exportgraphics(Fig,append(app.ExportPath,'\',app.NameoffileEditField.Value,'_tracking_density_plot.jpg'));
                    close(Fig)
                case 'Tool path plots'
                    for i = 1:length(app.PathAxes)
                        exportgraphics(app.PathAxes{i},append(app.ExportPath,'\',app.NameoffileEditField.Value,num2str(i),'.jpg'))
                    end
                case 'Tool path plot'
                    exportgraphics(app.UIAxes,append(app.ExportPath,'\',app.NameoffileEditField.Value,'.jpg'))
                case 'Samples'
                    for i = 1:length(app.Strats)
                        writestruct(app.Strats{i}.setup,append(app.ExportPath,'\Strategy',num2str(i),'.xml'));
                        writecell(app.Strats{i}.code,append(app.ExportPath,'\code',num2str(i),'.txt'));
                        writematrix(app.DiscreteDist,append(app.ExportPath,'\',app.NameoffileEditField.Value,num2str(i),'.csv'));
                    end
                case 'Sample density plot'
                    exportgraphics(app.SamplevisualiseUIAxes,append(app.ExportPath,'\',app.NameoffileEditField.Value,'.jpg'))
            end
            
            
        end

        % Button pushed function: DirButton
        function DirButtonPushed(app, event)
            app.ExportPath = uigetdir();
            app.ExportdatapathTextArea.Value = app.ExportPath;
        end

        % Button pushed function: GeneratesamplesButton
        function GeneratesamplesButtonPushed(app, event)
            %% Generate sample space %%
            
            % Save user defined variables
            app.Params.Sampling.Num = app.NumberofsamplesEditField.Value;
            app.Params.Sampling.StrategyName = app.SamplingstrategyDropDown.Value;
            app.Params.Sampling.DimMag = app.DimensionmagnitudeEditField.Value; 
            app.Params.Sampling.MinArea = app.MinimumtrackedareaEditField.Value;
            
            % Form boundary values for sample space
            switch app.Params.Sampling.StrategyName
                case 'Centre to outside'
                    app.Params.Sampling.Bound = [
                        app.MinimumwidthEditField.Value,app.MaximumwidthEditField.Value;
                        app.MinimumheightEditField.Value,app.MaximumheightEditField.Value;
                        app.MinimumxpositionEditField.Value,app.MaximumxpositionEditField.Value;
                        app.MinimumypositionEditField.Value,app.MaximumypositionEditField.Value;
                        app.MinimumscalingEditField.Value,app.MaximumscalingEditField.Value
                        ];
                case 'Vertical expand'
                    app.Params.Sampling.Bound = [
                        app.MinimumwidthEditField_2.Value,app.MaximumwidthEditField_2.Value;
                        app.MinimumxpositionEditField_2.Value,app.MaximumxpositionEditField_2.Value;
                        app.MinimumscalingEditField_2.Value,app.MaximumscalingEditField_2.Value
                        ];
                case 'Horizontal expand'
                    app.Params.Sampling.Bound = [
                        app.MinimumheightEditField_2.Value,app.MaximumheightEditField_2.Value;
                        app.MinimumypositionEditField_2.Value,app.MaximumypositionEditField_2.Value;
                        app.MinimumscalingEditField_3.Value,app.MaximumscalingEditField_3.Value
                        ];
                case 'Overlayed'
                    app.Params.Sampling.Bound = [
                        app.MinimumwidthEditField_3.Value,app.MaximumwidthEditField_3.Value;
                        app.MinimumheightEditField_3.Value,app.MaximumheightEditField_3.Value;
                        app.MinimumxpositionEditField_3.Value,app.MaximumxpositionEditField_3.Value;
                        app.MinimumypositionEditField_3.Value,app.MaximumypositionEditField_3.Value;
                        ];
                case 'Triangle'
                    app.Params.Sampling.Bound = [
                        app.MinimumbasewidthEditField.Value,app.MaximumbasewidthEditField.Value;
                        app.MinimumheightEditField_4.Value,app.MaximumheightEditField_4.Value;
                        app.MinimumxpositionEditField_4.Value,app.MaximumxpositionEditField_4.Value;
                        app.MinimumypositionEditField_4.Value,app.MaximumypositionEditField_4.Value;
                        ];
            end
            
            names = {};
            for i = 1:length(app.Params.Sampling.Bound(:,1))
                names{i} = append('Parameter',num2str(i));
            end
            
            app.SamplespaceUITable.ColumnName = names;
            app.SampleselectionUITable.ColumnName = names;
            
            % Generate sample space
            [sol_set,raw_dt,area_dt] = GenerateSamples( ...
                app.Params.Sampling.Bound, ...
                app.Params.Sampling.Num, ...
                app.Params.Sampling.StrategyName, ...
                app.Params.Path.WheelBound, ...
                app.Params.Sampling.DimMag, ...
                app.Params.Sheet.SheetSize, ...
                app.Params.Sampling.MinArea);
            
            app.SampleSet = sol_set;
            app.SampleRawData = raw_dt;
            app.AreaData = area_dt;
            raw_tab = num2cell(raw_dt);
            sol_tab = num2cell(sol_set);
            
            app.SamplespaceUITable.Data = raw_tab;
            app.SampleselectionUITable.Data = sol_tab;
            
        end

        % Button pushed function: VisualiseButton
        function VisualiseButtonPushed(app, event)
            %% Visualise selected sample %%
            
            % Get data indexed by spinner
            data = app.SampleSet(app.SamplenumberSpinner.Value,:);
            SheetSize = app.Params.Sheet.SheetSize;
            app.Params.Strategy.Name = app.Params.Sampling.StrategyName;
            app.Params.Sampling.Resolution = app.SamplingresolutionEditField.Value;
            app.Params.Sampling.ResolutionNorm = app.SamplingresolutionEditField.Value/app.Params.Sheet.SheetSize;
            app.Params.Sampling.Discrete = app.SamplingdiscreteCheckBox.Value;
            app.Params.Process.Discrete = app.SamplingdiscreteCheckBox.Value;
            app.Params.Process.Resolution = app.SamplingresolutionEditField.Value;
            app.Params.Process.ResolutionNorm = app.SamplingresolutionEditField.Value/app.Params.Sheet.SheetSize;
%             app.SampleAxes = cell(length(app.Strats),1);

            if app.Params.Sampling.Discrete
                switch app.Params.Sampling.StrategyName
                    case 'Centre to outside'
                        data(1) = round(data(1)/(2*app.Params.Sampling.Resolution))*(2*app.Params.Sampling.Resolution);
                        data(2) = round((data(2))/(2*app.Params.Sampling.Resolution))*(2*app.Params.Sampling.Resolution);
                        data(3) = round((data(3))/(app.Params.Sampling.Resolution))*(app.Params.Sampling.Resolution);
                        data(4) = round((data(4))/(app.Params.Sampling.Resolution))*(app.Params.Sampling.Resolution);
%                         data(5) = round((((data(5)-1)/(app.Params.Strategy.NumPasses-1))*)/(app.Params.Sampling.Resolution))*(app.Params.Sampling.Resolution);
                    case 'Vertical expand'
                        data(1) = round((data(1))/(2*app.Params.Sampling.Resolution))*(2*app.Params.Sampling.Resolution);
                        data(2) = round((data(2))/(app.Params.Sampling.Resolution))*(app.Params.Sampling.Resolution);
                    case 'Horizontal expand'
                        data(1) = round((data(1))/(2*app.Params.Sampling.Resolution))*(2*app.Params.Sampling.Resolution);
                        data(2) = round((data(2))/(app.Params.Sampling.Resolution))*(app.Params.Sampling.Resolution);
                    case 'Overlayed'
                        data(1) = round((data(1))/(2*app.Params.Sampling.Resolution))*(2*app.Params.Sampling.Resolution);
                        data(2) = round((data(2))/(2*app.Params.Sampling.Resolution))*(2*app.Params.Sampling.Resolution);
                        data(3) = round((data(3))/(app.Params.Sampling.Resolution))*(app.Params.Sampling.Resolution);
                        data(4) = round((data(4))/(app.Params.Sampling.Resolution))*(app.Params.Sampling.Resolution);
                    case 'Triangle'
                        data(1) = round((data(1))/(2*app.Params.Sampling.Resolution))*(2*app.Params.Sampling.Resolution);
                        data(2) = round((data(2))/(app.Params.Sampling.Resolution))*(app.Params.Sampling.Resolution);
                        data(3) = round((data(3))/(app.Params.Sampling.Resolution))*(app.Params.Sampling.Resolution);
                        data(4) = round((data(4))/(app.Params.Sampling.Resolution))*(app.Params.Sampling.Resolution);
                end
            end
            
            % Set Params.strategy structure equal to values
            switch app.Params.Sampling.StrategyName
                case 'Centre to outside'
                    app.Params.Strategy.Value.MinWidth = data(1);
                    app.Params.Strategy.Value.MinHeight = data(2);
                    app.Params.Strategy.Value.Centrex = data(3);
                    app.Params.Strategy.Value.Centrey = data(4);
                    app.Params.Strategy.Value.ScalingFactor = data(5);
                    app.Params.Strategy.Norm.MinWidth = data(1)/SheetSize;
                    app.Params.Strategy.Norm.MinHeight = data(2)/SheetSize;
                    app.Params.Strategy.Norm.Centrex = data(3)/SheetSize;
                    app.Params.Strategy.Norm.Centrey = data(4)/SheetSize;
                case 'Vertical expand'
                    app.Params.Strategy.Value.MinWidth = data(1);
                    app.Params.Strategy.Value.Centrex = data(2);
                    app.Params.Strategy.Value.ScalingFactor = data(3);
                    app.Params.Strategy.Norm.MinWidth = data(1)/SheetSize;
                    app.Params.Strategy.Norm.Centrex = data(2)/SheetSize;
                case 'Horizontal expand'
                    app.Params.Strategy.Value.MinHeight = data(1);
                    app.Params.Strategy.Value.Centrey = data(2);
                    app.Params.Strategy.Value.ScalingFactor = data(3);
                    app.Params.Strategy.Norm.MinHeight = data(1)/SheetSize;
                    app.Params.Strategy.Norm.Centrey = data(2)/SheetSize;
                case 'Overlayed'
                    app.Params.Strategy.Value.Width = data(1);
                    app.Params.Strategy.Value.Height = data(2);
                    app.Params.Strategy.Value.Centrex = data(3);
                    app.Params.Strategy.Value.Centrey = data(4);
                    app.Params.Strategy.Norm.Width = data(1)/SheetSize;
                    app.Params.Strategy.Norm.Height = data(2)/SheetSize;
                    app.Params.Strategy.Norm.Centrex = data(3)/SheetSize;
                    app.Params.Strategy.Norm.Centrey = data(4)/SheetSize;
                case 'Triangle'
                    app.Params.Strategy.Value.BaseWidth = data(1);
                    app.Params.Strategy.Value.Height = data(2);
                    app.Params.Strategy.Value.BaseCentrex = data(3);
                    app.Params.Strategy.Value.BaseCentrey = data(4);
                    app.Params.Strategy.Norm.BaseWidth = data(1)/SheetSize;
                    app.Params.Strategy.Norm.Height = data(2)/SheetSize;
                    app.Params.Strategy.Norm.BaseCentrex = data(3)/SheetSize;
                    app.Params.Strategy.Norm.BaseCentrey = data(4)/SheetSize;
            end
            
            % Number of cells for density estimation
            NumCells = 1/app.Params.Path.SpacingNorm;
            
            % Initialise density estimation parameter
            app.DiscreteDist = [];
            
            % Calculate strategies for selected parameters
            for i = 1:app.Params.Strategy.Value.NumPasses
                
                % Determine intermediate nodes at transfer points
                app.MS = AddNodes(app.Params,i);
                app.MS.setup = app.Params;
            
                % Calculate kinematics of the machine axes
                app.MS = CalculateKinematics(app.MS,app.Params);
                
                % Add to density estimation parameter
                app.DiscreteDist = [app.DiscreteDist; app.MS.DiscretePath];
                
                % Collect strategy
                app.Strats{i} = app.MS;

            end
            % Initialise axis
            axis(app.SamplevisualiseUIAxes,[0 1 0 1])
            pbaspect(app.SamplevisualiseUIAxes,[1 1 1])
            hold(app.SamplevisualiseUIAxes,'on');
            xlabel(app.SamplevisualiseUIAxes,'X');
            ylabel(app.SamplevisualiseUIAxes,'Y');
            
            if app.Params.Sampling.Discrete
                
                grid(app.SamplevisualiseUIAxes,'minor')
                grid(app.SamplevisualiseUIAxes,'on')
                
                app.SamplevisualiseUIAxes.XAxis.MinorTick = 'on';
                app.SamplevisualiseUIAxes.XAxis.MinorTickValues = 0:app.Params.Sampling.ResolutionNorm:1;
                
                app.SamplevisualiseUIAxes.YAxis.MinorTick = 'on';
                app.SamplevisualiseUIAxes.YAxis.MinorTickValues = 0:app.Params.Sampling.ResolutionNorm:1;
                
                app.SamplevisualiseUIAxes.XTick = 0:0.2:1;
                app.SamplevisualiseUIAxes.YTick = 0:0.2:1;
            else
                grid(app.SamplevisualiseUIAxes,'off')
                set(app.SamplevisualiseUIAxes,'xtick',[0:0.2:1])
                set(app.SamplevisualiseUIAxes,'ytick',[0:0.2:1])
            end
            
            % Plot tracking density
            for j = 1:length(app.Strats)
                fill(app.SamplevisualiseUIAxes,app.Strats{j}.AreaCorners(:,1),app.Strats{j}.AreaCorners(:,2)','r','FaceAlpha',0.1,'LineStyle','none')
            end
           
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.SamplevisualiseUIAxes,'reset')
            
        end

        % Value changed function: ContinuousCheckBox
        function ContinuousCheckBoxValueChanged(app, event)
            if app.DiscreteCheckBox.Value && app.ContinuousCheckBox.Value
                app.DiscreteCheckBox.Value = 0;
            end
        end

        % Value changed function: DiscreteCheckBox
        function DiscreteCheckBoxValueChanged(app, event)
            if app.DiscreteCheckBox.Value && app.ContinuousCheckBox.Value
                app.ContinuousCheckBox.Value = 0;
            end
        end

        % Value changed function: SamplingdiscreteCheckBox
        function SamplingdiscreteCheckBoxValueChanged(app, event)
            if app.SamplingdiscreteCheckBox.Value && app.SamplingcontinuousCheckBox.Value
                app.SamplingcontinuousCheckBox.Value = 0;
            end
        end

        % Value changed function: SamplingcontinuousCheckBox
        function SamplingcontinuousCheckBoxValueChanged(app, event)
            if app.SamplingdiscreteCheckBox.Value && app.SamplingcontinuousCheckBox.Value
                app.SamplingdiscreteCheckBox.Value = 0;
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            %% Plot area variation %%
            
            wheel_len = app.Params.Sheet.SheetSize - 2*app.Params.Path.WheelBound;
            
            area = 0:0.1:1;
            num = zeros(1,11);
            area_tot = (wheel_len)^2;
            dt = {};
            
            for i = 1:length(area)
                switch app.Params.Sampling.StrategyName
                    case 'Centre to outside'
                        areas = (app.AreaData(:,1).*app.AreaData(:,2).*(app.AreaData(:,5).^(2)))./area_tot;
                        num(i) = length(app.AreaData(areas < area(i),1))/length(app.AreaData(:,1));
                        
                        app.BoxplotData{i} = app.AreaData(areas >= area(i),:);
                    case 'Vertical expand'
                        areas = (app.AreaData(:,1).*wheel_len.*app.AreaData(:,3))./area_tot;
                        num(i) = length(app.AreaData(areas < area(i),1))/length(app.AreaData(:,1));
                        
                        app.BoxplotData{i} = app.AreaData(areas >= area(i),:);
                    case 'Horizontal expand'
                        areas = (app.AreaData(:,1).*wheel_len.*app.AreaData(:,3))./area_tot;
                        num(i) = length(app.AreaData(areas < area(i),1))/length(app.AreaData(:,1));
                        
                        app.BoxplotData{i} = app.AreaData(areas >= area(i),:);
                    case 'Overlayed'
                        areas = app.AreaData(:,1).*app.AreaData(:,2)./area_tot;
                        num(i) = length(app.AreaData(areas < area(i),1))/length(app.AreaData(:,1));
                        
                        app.BoxplotData{i} = app.AreaData(areas >= area(i),:);
                    case 'Triangle'
                        areas = 0.5 * app.AreaData(:,1).*app.AreaData(:,2)./area_tot;
                        num(i) = length(app.AreaData(areas < area(i),1))/length(app.AreaData(:,1));
                        
                        app.BoxplotData{i} = app.AreaData(areas >= area(i),:);
                end
            end
            
            plot(app.AreaUIAxes,area*100,num*100)
            grid(app.AreaUIAxes,'on')
            xticks(app.AreaUIAxes,[0 20 40 60 80 100])
            yticks(app.AreaUIAxes,[0 20 40 60 80 100])
            xlabel(app.AreaUIAxes,'Area covered by tracking pattern (%)')
            ylabel(app.AreaUIAxes,'Number of samples removed (%)')
            
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            %% Plot box plots %%
            
            paramind = str2double(app.SelectparameterDropDown.Value);
            bxdata = [];
            g = [];
            
            for i = 1:10
                bxdata = [bxdata ; app.BoxplotData{i}(:,paramind)];
                g = [g ; i*ones(size(app.BoxplotData{i}(:,paramind)))];
            end
            
            boxplot(app.ParamUIAxes,bxdata,g)
            xticklabels(app.ParamUIAxes,{'0' '10' '20' '30' '40' '50' '60' '70' '80' '90'})
            xlabel(app.ParamUIAxes,'Area covered by tracking pattern (%)')
            ylabel(app.ParamUIAxes,'Scaling factor')
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
            %% Export sampling data %%
            
            switch app.ExportoptionsDropDown_2.Value
                case 'Strategies'
                    for i = 1:length(app.Strats)
                        writestruct(app.Strats{i}.setup,append(app.SamplingExportPath,'\',app.NameoffileEditField_2.Value,'_strategy_',num2str(i),'.xml'));
                    end
                case 'G-code'
                    for i = 1:length(app.Strats)
                        writecell(app.Strats{i}.code,append(app.SamplingExportPath,'\',app.NameoffileEditField_2.Value,'_code_',num2str(i),'.txt'));
                    end
                case 'Tracking density'
                    writematrix(app.DiscreteDist,append(app.SamplingExportPath,'\',app.NameoffileEditField_2.Value,'_tracking_density.csv'));
                case 'Tracking density plot'
                    Fig = figure;
                    ax = copyobj(app.SamplevisualiseUIAxes, Fig);
                    set(ax, 'Units', 'Normalized'); % First change to normalized units.
                    set(ax, 'OuterPosition', [.1, .1, .85, .85]);
                    %hgsave(Fig,append(app.SamplingExportPath,'\',app.NameoffileEditField_2.Value,'_tracking_density_plot.fig'));
                    exportgraphics(Fig,append(app.SamplingExportPath,'\',app.NameoffileEditField_2.Value,'_tracking_density_plot.jpg'));
                    close(Fig)
                case 'Matlab figure'
                    Fig = figure;
                    ax = copyobj(app.SamplevisualiseUIAxes, Fig);
                    set(ax, 'Units', 'Normalized'); % First change to normalized units.
                    set(ax, 'OuterPosition', [.1, .1, .85, .85]);
                    hgsave(Fig,append(app.SamplingExportPath,'\',app.NameoffileEditField_2.Value,'_tracking_density_plot.fig'));
                    close(Fig)
                case 'Tool path plots'
                    for i = 1:length(app.PathAxes)
                        exportgraphics(app.PathAxes{i},append(app.SamplingExportPath,'\',app.NameoffileEditField_2.Value,'_toolpath_',num2str(i),'.jpg'))
                    end
                case 'Area variation plot'
                    Fig = figure;
                    ax = copyobj(app.AreaUIAxes, Fig);
                    set(ax, 'Units', 'Normalized'); % First change to normalized units.
                    set(ax, 'OuterPosition', [.1, .1, .85, .85]);
                    exportgraphics(Fig,append(app.SamplingExportPath,'\',app.NameoffileEditField_2.Value,'_area_var.jpg'))
                    close(Fig)
                case 'Parameter variation plot'
                    Fig = figure;
                    ax = copyobj(app.ParamUIAxes, Fig);
                    set(ax, 'Units', 'Normalized'); % First change to normalized units.
                    set(ax, 'OuterPosition', [.1, .1, .85, .85]);
                    exportgraphics(Fig,append(app.SamplingExportPath,'\',app.NameoffileEditField_2.Value,'_box_plt.jpg'))
                    close(Fig)
            end
        end

        % Button pushed function: DirButton_2
        function DirButton_2Pushed(app, event)
            app.SamplingExportPath = uigetdir();
            app.ExportdatapathTextArea_2.Value = app.SamplingExportPath;
        end

        % Value changed function: AxesoffCheckBox
        function AxesoffCheckBoxValueChanged(app, event)
            if app.AxesoffCheckBox.Value
                set(app.UIAxes5,'XColor', 'none','YColor','none')
            else
                set(app.UIAxes5,'XColor', '#000000','YColor','#000000')
            end
            
        end

        % Button pushed function: GenerateButton_2
        function GenerateButton_2Pushed(app, event)
            % Initialise axis
            axis(app.UIAxes,[0 1 0 1])
            hold(app.UIAxes,'on');
            pbaspect(app.UIAxes,[1 1 1])
            app.PathAxes = cell(length(app.Strats),1);
            strat = app.Strats{app.PassnoSpinner.Value};
            
            % Initialise nodal arrays
            profilex = zeros(1,length(strat.node));
            profiley = zeros(1,length(strat.node));
            scatterx = zeros(1,length(strat.node));
            scattery = zeros(1,length(strat.node));
            
            % Plot all nodal points
            for i = 1:length(strat.node)
                scatterx(i) = strat.node(i).datum.global.position(1);
                scattery(i) = strat.node(i).datum.global.position(2);
                profilex(i) = strat.node(i).datum.global.position(1);
                profiley(i) = strat.node(i).datum.global.position(2);
            end
            
            % Plot toolpath
            app.TPScatter = scatter(app.UIAxes,scatterx,scattery,'bx');
            plot(app.UIAxes,profilex,profiley,'r')
            xlabel(app.UIAxes,'X')
            ylabel(app.UIAxes,'Y')
          
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            cla(app.UIAxes,'reset')
        end

        % Value changed function: AxesoffCheckBox_2
        function AxesoffCheckBox_2ValueChanged(app, event)
            if app.AxesoffCheckBox_2.Value
                set(app.UIAxes,'XColor', 'none','YColor','none')
            else
                set(app.UIAxes,'XColor', '#000000','YColor','#000000')
            end
        end

        % Value changed function: ShowborderCheckBox
        function ShowborderCheckBoxValueChanged(app, event)
            if app.ShowborderCheckBox.Value
                app.TPBorder = plot(app.UIAxes,[0 0 1 1 0],[0 1 1 0 0],'k','LineWidth','2');
            else
                delete(app.TPBorder)
            end
        end

        % Value changed function: ShownodesCheckBox
        function ShownodesCheckBoxValueChanged(app, event)
            if app.ShownodesCheckBox.Value
                set(app.TPScatter,'Visible','on')
            else
                set(app.TPScatter,'Visible','off')
            end
        end

        % Value changed function: ShowborderCheckBox_2
        function ShowborderCheckBox_2ValueChanged(app, event)
            if app.ShowborderCheckBox_2.Value
                app.DBorder = plot(app.UIAxes5,[0 0 1 1 0],[0 1 1 0 0],'k','LineWidth','2');
            else
                delete(app.DBorder)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {560, 560};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.InputparametersTab = uitab(app.TabGroup2);
            app.InputparametersTab.Title = 'Input parameters';
            app.SheetparametersPanel = uipanel(app.InputparametersTab);
            app.SheetparametersPanel.Title = 'Sheet parameters';
            app.GridLayout7 = uigridlayout(app.SheetparametersPanel);
            app.SheetsizeEditField = uieditfield(app.GridLayout7, 'numeric');
            app.SheetsizeEditField.Value = 500;
            app.SheetsizeEditFieldLabel = uilabel(app.GridLayout7);
            app.SheetsizeEditFieldLabel.Text = 'Sheet size';
            app.MaterialEditField = uieditfield(app.GridLayout7, 'text');
            app.MaterialEditField.Value = 'Steel';
            app.MaterialEditFieldLabel = uilabel(app.GridLayout7);
            app.MaterialEditFieldLabel.Text = 'Material';
            app.mmLabel_16 = uilabel(app.GridLayout7);
            app.mmLabel_16.Text = 'mm';
            app.MachineparametersPanel = uipanel(app.InputparametersTab);
            app.MachineparametersPanel.Title = 'Machine parameters';
            app.GridLayout9 = uigridlayout(app.MachineparametersPanel);
            app.RollgapEditFieldLabel = uilabel(app.GridLayout9);
            app.RollgapEditFieldLabel.Text = 'Roll gap';
            app.RollgapEditField = uieditfield(app.GridLayout9, 'numeric');
            app.RollgapEditField.Value = 0.5;
            app.FeedrateEditFieldLabel = uilabel(app.GridLayout9);
            app.FeedrateEditFieldLabel.Text = 'Feed rate';
            app.FeedrateEditField = uieditfield(app.GridLayout9, 'numeric');
            app.FeedrateEditField.Value = 0.4;
            app.TooltypeDropDownLabel = uilabel(app.GridLayout9);
            app.TooltypeDropDownLabel.Text = 'Tool type';
            app.TooltypeDropDown = uidropdown(app.GridLayout9);
            app.TooltypeDropDown.Items = {'A', 'B', 'C', 'D'};
            app.TooltypeDropDown.Value = 'A';
            app.ArcspeedEditFieldLabel = uilabel(app.GridLayout9);
            app.ArcspeedEditFieldLabel.Text = 'Arc speed';
            app.ArcspeedEditField = uieditfield(app.GridLayout9, 'numeric');
            app.ArcspeedEditField.Value = 0.4;
            app.mmLabel_9 = uilabel(app.GridLayout9);
            app.mmLabel_9.Text = 'mm';
            app.mmsLabel = uilabel(app.GridLayout9);
            app.mmsLabel.Text = 'mm/s';
            app.mmsLabel_2 = uilabel(app.GridLayout9);
            app.mmsLabel_2.Text = 'mm/s';
            app.ProcessingparametersPanel = uipanel(app.InputparametersTab);
            app.ProcessingparametersPanel.Title = 'Processing parameters';
            app.GridLayout21 = uigridlayout(app.ProcessingparametersPanel);
            app.ResolutionEditField = uieditfield(app.GridLayout21, 'numeric');
            app.ResolutionEditField.Value = 10;
            app.ResolutionEditFieldLabel = uilabel(app.GridLayout21);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.mmLabel_57 = uilabel(app.GridLayout21);
            app.mmLabel_57.Text = 'mm';
            app.ContinuousCheckBox = uicheckbox(app.GridLayout21);
            app.ContinuousCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinuousCheckBoxValueChanged, true);
            app.ContinuousCheckBox.Text = 'Continuous';
            app.DiscreteCheckBox = uicheckbox(app.GridLayout21);
            app.DiscreteCheckBox.ValueChangedFcn = createCallbackFcn(app, @DiscreteCheckBoxValueChanged, true);
            app.DiscreteCheckBox.Text = 'Discrete';
            app.ToolpathparametersPanel = uipanel(app.InputparametersTab);
            app.ToolpathparametersPanel.Title = 'Tool path parameters';
            app.GridLayout12 = uigridlayout(app.ToolpathparametersPanel);
            app.EndeffectorboundaryEditFieldLabel = uilabel(app.GridLayout12);
            app.EndeffectorboundaryEditFieldLabel.Text = {'End effector'; 'boundary'};
            app.EndeffectorboundaryEditField = uieditfield(app.GridLayout12, 'numeric');
            app.EndeffectorboundaryEditField.Value = 30;
            app.TrackingspacingEditFieldLabel = uilabel(app.GridLayout12);
            app.TrackingspacingEditFieldLabel.Text = 'Tracking spacing';
            app.TrackingspacingEditField = uieditfield(app.GridLayout12, 'numeric');
            app.TrackingspacingEditField.Value = 4;
            app.mmLabel_29 = uilabel(app.GridLayout12);
            app.mmLabel_29.Text = 'mm';
            app.mmLabel_30 = uilabel(app.GridLayout12);
            app.mmLabel_30.Text = 'mm';
            app.DrivethroughdistanceEditField = uieditfield(app.GridLayout12, 'numeric');
            app.DrivethroughdistanceEditField.Value = 50;
            app.DrivethroughdistanceLabel = uilabel(app.GridLayout12);
            app.DrivethroughdistanceLabel.Text = {'Drive through '; 'distance'};
            app.mmLabel_55 = uilabel(app.GridLayout12);
            app.mmLabel_55.Text = 'mm';
            app.FixedwheelingboundaryEditFieldLabel = uilabel(app.GridLayout12);
            app.FixedwheelingboundaryEditFieldLabel.Text = {'Fixed wheeling '; 'boundary'};
            app.FixedwheelingboundaryEditField = uieditfield(app.GridLayout12, 'numeric');
            app.FixedwheelingboundaryEditField.Value = 50;
            app.mmLabel_56 = uilabel(app.GridLayout12);
            app.mmLabel_56.Text = 'mm';
            app.GenerateprofileTab = uitab(app.TabGroup2);
            app.GenerateprofileTab.Title = 'Generate profile';
            app.TabGroup3 = uitabgroup(app.GenerateprofileTab);
            app.ProfileparametersTab = uitab(app.TabGroup3);
            app.ProfileparametersTab.Title = 'Profile parameters';
            app.TabGroup4 = uitabgroup(app.ProfileparametersTab);
            app.CentretooutsideTab = uitab(app.TabGroup4);
            app.CentretooutsideTab.Title = 'Centre to outside';
            app.GridLayout10 = uigridlayout(app.CentretooutsideTab);
            app.MinimumwidthEditFieldLabel = uilabel(app.GridLayout10);
            app.MinimumwidthEditFieldLabel.Text = 'Minimum width';
            app.C2OminimumwidthEditField = uieditfield(app.GridLayout10, 'numeric');
            app.C2OminimumwidthEditField.Value = 40;
            app.CentralxpositionEditFieldLabel = uilabel(app.GridLayout10);
            app.CentralxpositionEditFieldLabel.Text = 'Central x position';
            app.C2OcentralxpositionEditField = uieditfield(app.GridLayout10, 'numeric');
            app.C2OcentralxpositionEditField.Value = 150;
            app.CentralypositionEditFieldLabel = uilabel(app.GridLayout10);
            app.CentralypositionEditFieldLabel.Text = 'Central y position';
            app.C2OcentralypositionEditField = uieditfield(app.GridLayout10, 'numeric');
            app.C2OcentralypositionEditField.Value = 150;
            app.MinimumheightEditFieldLabel = uilabel(app.GridLayout10);
            app.MinimumheightEditFieldLabel.Text = 'Minimum height';
            app.C2OminimumheightEditField = uieditfield(app.GridLayout10, 'numeric');
            app.C2OminimumheightEditField.Value = 40;
            app.NumberofpassesEditField_2Label = uilabel(app.GridLayout10);
            app.NumberofpassesEditField_2Label.Text = 'Number of passes';
            app.C2OnumberofpassesEditField = uieditfield(app.GridLayout10, 'numeric');
            app.C2OnumberofpassesEditField.Value = 4;
            app.ScalingfactorEditFieldLabel = uilabel(app.GridLayout10);
            app.ScalingfactorEditFieldLabel.Text = 'Scaling factor';
            app.C2OscalingfactorEditField = uieditfield(app.GridLayout10, 'numeric');
            app.C2OscalingfactorEditField.Value = 3;
            app.mmLabel_51 = uilabel(app.GridLayout10);
            app.mmLabel_51.Text = 'mm';
            app.mmLabel_52 = uilabel(app.GridLayout10);
            app.mmLabel_52.Text = 'mm';
            app.mmLabel_53 = uilabel(app.GridLayout10);
            app.mmLabel_53.Text = 'mm';
            app.mmLabel_54 = uilabel(app.GridLayout10);
            app.mmLabel_54.Text = 'mm';
            app.DiagonalTab = uitab(app.TabGroup4);
            app.DiagonalTab.Title = 'Diagonal';
            app.GridLayout13 = uigridlayout(app.DiagonalTab);
            app.DiagwidthEditField = uieditfield(app.GridLayout13, 'numeric');
            app.DiagwidthEditField.Value = 40;
            app.WidthEditFieldLabel = uilabel(app.GridLayout13);
            app.WidthEditFieldLabel.Text = 'Width';
            app.DiagheightEditField = uieditfield(app.GridLayout13, 'numeric');
            app.DiagheightEditField.Value = 40;
            app.HeightEditFieldLabel = uilabel(app.GridLayout13);
            app.HeightEditFieldLabel.Text = 'Height';
            app.DiagnumberofpassesEditField = uieditfield(app.GridLayout13, 'numeric');
            app.DiagnumberofpassesEditField.Value = 10;
            app.NumberofpassesEditField_3Label = uilabel(app.GridLayout13);
            app.NumberofpassesEditField_3Label.Text = 'Number of passes';
            app.DiagstartxpositionEditField = uieditfield(app.GridLayout13, 'numeric');
            app.DiagstartxpositionEditField.Value = 50;
            app.StartxpositionEditFieldLabel = uilabel(app.GridLayout13);
            app.StartxpositionEditFieldLabel.Text = 'Start x position';
            app.DiagstartypositionEditField = uieditfield(app.GridLayout13, 'numeric');
            app.DiagstartypositionEditField.Value = 50;
            app.StartypositionEditFieldLabel = uilabel(app.GridLayout13);
            app.StartypositionEditFieldLabel.Text = 'Start y position';
            app.mmLabel_47 = uilabel(app.GridLayout13);
            app.mmLabel_47.Text = 'mm';
            app.mmLabel_48 = uilabel(app.GridLayout13);
            app.mmLabel_48.Text = 'mm';
            app.mmLabel_49 = uilabel(app.GridLayout13);
            app.mmLabel_49.Text = 'mm';
            app.mmLabel_50 = uilabel(app.GridLayout13);
            app.mmLabel_50.Text = 'mm';
            app.VerticalexpandTab = uitab(app.TabGroup4);
            app.VerticalexpandTab.Title = 'Vertical expand';
            app.GridLayout14 = uigridlayout(app.VerticalexpandTab);
            app.VertscalingfactorEditField = uieditfield(app.GridLayout14, 'numeric');
            app.VertscalingfactorEditField.Value = 3;
            app.ScalingfactorEditField_2Label = uilabel(app.GridLayout14);
            app.ScalingfactorEditField_2Label.Text = 'Scaling factor';
            app.VertnumberofpassesEditField = uieditfield(app.GridLayout14, 'numeric');
            app.VertnumberofpassesEditField.Value = 4;
            app.NumberofpassesEditField_4Label = uilabel(app.GridLayout14);
            app.NumberofpassesEditField_4Label.Text = 'Number of passes';
            app.VertminimumwidthEditField = uieditfield(app.GridLayout14, 'numeric');
            app.VertminimumwidthEditField.Value = 50;
            app.MinimumwidthEditField_2Label = uilabel(app.GridLayout14);
            app.MinimumwidthEditField_2Label.Text = 'Minimum width';
            app.VertcentralxpositionEditField = uieditfield(app.GridLayout14, 'numeric');
            app.VertcentralxpositionEditField.Value = 150;
            app.CentralxpositionEditField_2Label = uilabel(app.GridLayout14);
            app.CentralxpositionEditField_2Label.Text = 'Central x position';
            app.mmLabel_45 = uilabel(app.GridLayout14);
            app.mmLabel_45.Text = 'mm';
            app.mmLabel_46 = uilabel(app.GridLayout14);
            app.mmLabel_46.Text = 'mm';
            app.HorizontalexpandTab = uitab(app.TabGroup4);
            app.HorizontalexpandTab.Title = 'Horizontal expand';
            app.GridLayout15 = uigridlayout(app.HorizontalexpandTab);
            app.HorscalingfactorEditField = uieditfield(app.GridLayout15, 'numeric');
            app.HorscalingfactorEditField.Value = 3;
            app.ScalingfactorEditField_3Label = uilabel(app.GridLayout15);
            app.ScalingfactorEditField_3Label.Text = 'Scaling factor';
            app.HornumberofpassesEditField = uieditfield(app.GridLayout15, 'numeric');
            app.HornumberofpassesEditField.Value = 4;
            app.NumberofpassesEditField_5Label = uilabel(app.GridLayout15);
            app.NumberofpassesEditField_5Label.Text = 'Number of passes';
            app.HorminimumheightEditField = uieditfield(app.GridLayout15, 'numeric');
            app.HorminimumheightEditField.Value = 40;
            app.MinimumheightEditField_2Label = uilabel(app.GridLayout15);
            app.MinimumheightEditField_2Label.Text = 'Minimum height';
            app.HorcentralypositionEditField = uieditfield(app.GridLayout15, 'numeric');
            app.HorcentralypositionEditField.Value = 150;
            app.CentralypositionEditField_2Label = uilabel(app.GridLayout15);
            app.CentralypositionEditField_2Label.Text = 'Central y position';
            app.mmLabel_43 = uilabel(app.GridLayout15);
            app.mmLabel_43.Text = 'mm';
            app.mmLabel_44 = uilabel(app.GridLayout15);
            app.mmLabel_44.Text = 'mm';
            app.OverlayedTab = uitab(app.TabGroup4);
            app.OverlayedTab.Title = 'Overlayed';
            app.GridLayout16 = uigridlayout(app.OverlayedTab);
            app.OverlaywidthEditField = uieditfield(app.GridLayout16, 'numeric');
            app.OverlaywidthEditField.Value = 200;
            app.WidthEditFieldLabel_2 = uilabel(app.GridLayout16);
            app.WidthEditFieldLabel_2.Text = 'Width';
            app.OverlayheightEditField = uieditfield(app.GridLayout16, 'numeric');
            app.OverlayheightEditField.Value = 200;
            app.HeightEditFieldLabel_2 = uilabel(app.GridLayout16);
            app.HeightEditFieldLabel_2.Text = 'Height';
            app.OverlaycentralxpositionEditField = uieditfield(app.GridLayout16, 'numeric');
            app.OverlaycentralxpositionEditField.Value = 150;
            app.CentralxpositionEditFieldLabel_2 = uilabel(app.GridLayout16);
            app.CentralxpositionEditFieldLabel_2.Text = 'Central x position';
            app.OverlaycentralypositionEditField = uieditfield(app.GridLayout16, 'numeric');
            app.OverlaycentralypositionEditField.Value = 150;
            app.CentralypositionEditFieldLabel_2 = uilabel(app.GridLayout16);
            app.CentralypositionEditFieldLabel_2.Text = 'Central y position';
            app.OverlaynumberofpassesEditField = uieditfield(app.GridLayout16, 'numeric');
            app.OverlaynumberofpassesEditField.Value = 4;
            app.NumberofpassesEditField_2Label_2 = uilabel(app.GridLayout16);
            app.NumberofpassesEditField_2Label_2.Text = 'Number of passes';
            app.mmLabel_39 = uilabel(app.GridLayout16);
            app.mmLabel_39.Text = 'mm';
            app.mmLabel_40 = uilabel(app.GridLayout16);
            app.mmLabel_40.Text = 'mm';
            app.mmLabel_41 = uilabel(app.GridLayout16);
            app.mmLabel_41.Text = 'mm';
            app.mmLabel_42 = uilabel(app.GridLayout16);
            app.mmLabel_42.Text = 'mm';
            app.TrackverticalTab = uitab(app.TabGroup4);
            app.TrackverticalTab.Title = 'Track vertical';
            app.GridLayout17 = uigridlayout(app.TrackverticalTab);
            app.TrackvertminimumheightEditField = uieditfield(app.GridLayout17, 'numeric');
            app.TrackvertminimumheightEditField.Value = 50;
            app.MinimumheightEditFieldLabel_2 = uilabel(app.GridLayout17);
            app.MinimumheightEditFieldLabel_2.Text = 'Minimum height';
            app.TrackvertmaximumheightEditField = uieditfield(app.GridLayout17, 'numeric');
            app.TrackvertmaximumheightEditField.Value = 200;
            app.MaximumheightEditFieldLabel = uilabel(app.GridLayout17);
            app.MaximumheightEditFieldLabel.Text = 'Maximum height';
            app.TrackvertnumberofpassesEditField = uieditfield(app.GridLayout17, 'numeric');
            app.TrackvertnumberofpassesEditField.Value = 10;
            app.NumberofpassesEditField_3Label_2 = uilabel(app.GridLayout17);
            app.NumberofpassesEditField_3Label_2.Text = 'Number of passes';
            app.mmLabel_37 = uilabel(app.GridLayout17);
            app.mmLabel_37.Text = 'mm';
            app.mmLabel_38 = uilabel(app.GridLayout17);
            app.mmLabel_38.Text = 'mm';
            app.TrackhorizontalTab = uitab(app.TabGroup4);
            app.TrackhorizontalTab.Title = 'Track horizontal';
            app.GridLayout18 = uigridlayout(app.TrackhorizontalTab);
            app.TrackhorminimumwidthEditField = uieditfield(app.GridLayout18, 'numeric');
            app.TrackhorminimumwidthEditField.Value = 50;
            app.MinimumwidthEditFieldLabel_2 = uilabel(app.GridLayout18);
            app.MinimumwidthEditFieldLabel_2.Text = 'Minimum width';
            app.TrackhormaximumwidthEditField = uieditfield(app.GridLayout18, 'numeric');
            app.TrackhormaximumwidthEditField.Value = 200;
            app.MaximumwidthEditFieldLabel = uilabel(app.GridLayout18);
            app.MaximumwidthEditFieldLabel.Text = 'Maximum width';
            app.TrackhornumberofpassesEditField = uieditfield(app.GridLayout18, 'numeric');
            app.TrackhornumberofpassesEditField.Value = 4;
            app.NumberofpassesEditField_4Label_2 = uilabel(app.GridLayout18);
            app.NumberofpassesEditField_4Label_2.Text = 'Number of passes';
            app.mmLabel_35 = uilabel(app.GridLayout18);
            app.mmLabel_35.Text = 'mm';
            app.mmLabel_36 = uilabel(app.GridLayout18);
            app.mmLabel_36.Text = 'mm';
            app.ConvergingTab = uitab(app.TabGroup4);
            app.ConvergingTab.Title = 'Converging';
            app.TriangleTab = uitab(app.TabGroup4);
            app.TriangleTab.Title = 'Triangle';
            app.GridLayout19 = uigridlayout(app.TriangleTab);
            app.TribasewidthEditField = uieditfield(app.GridLayout19, 'numeric');
            app.TribasewidthEditField.Value = 200;
            app.BasewidthEditFieldLabel = uilabel(app.GridLayout19);
            app.BasewidthEditFieldLabel.Text = 'Base width';
            app.TriheightEditField = uieditfield(app.GridLayout19, 'numeric');
            app.TriheightEditField.Value = 200;
            app.HeightEditFieldLabel_3 = uilabel(app.GridLayout19);
            app.HeightEditFieldLabel_3.Text = 'Height';
            app.TribasecentrexpositionEditField = uieditfield(app.GridLayout19, 'numeric');
            app.TribasecentrexpositionEditField.Value = 150;
            app.BasecentrexpositionEditFieldLabel = uilabel(app.GridLayout19);
            app.BasecentrexpositionEditFieldLabel.Text = 'Base centre x position';
            app.TribasecentreypositionEditField = uieditfield(app.GridLayout19, 'numeric');
            app.TribasecentreypositionEditField.Value = 50;
            app.BasecentreypositionEditFieldLabel = uilabel(app.GridLayout19);
            app.BasecentreypositionEditFieldLabel.Text = 'Base centre y position';
            app.TrinumberofpassesEditField = uieditfield(app.GridLayout19, 'numeric');
            app.TrinumberofpassesEditField.Value = 4;
            app.NumberofpassesEditField_3Label_3 = uilabel(app.GridLayout19);
            app.NumberofpassesEditField_3Label_3.Text = 'Number of passes';
            app.mmLabel_31 = uilabel(app.GridLayout19);
            app.mmLabel_31.Text = 'mm';
            app.mmLabel_32 = uilabel(app.GridLayout19);
            app.mmLabel_32.Text = 'mm';
            app.mmLabel_33 = uilabel(app.GridLayout19);
            app.mmLabel_33.Text = 'mm';
            app.mmLabel_34 = uilabel(app.GridLayout19);
            app.mmLabel_34.Text = 'mm';
            app.TrackingdensityTab = uitab(app.TabGroup3);
            app.TrackingdensityTab.Title = 'Tracking density';
            app.UIAxes5 = uiaxes(app.TrackingdensityTab);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.AxesoffCheckBox = uicheckbox(app.TrackingdensityTab);
            app.AxesoffCheckBox.ValueChangedFcn = createCallbackFcn(app, @AxesoffCheckBoxValueChanged, true);
            app.AxesoffCheckBox.Text = 'Axes off?';
            app.ShowborderCheckBox_2 = uicheckbox(app.TrackingdensityTab);
            app.ShowborderCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ShowborderCheckBox_2ValueChanged, true);
            app.ShowborderCheckBox_2.Text = 'Show border?';
            app.ToolpathTab = uitab(app.TabGroup3);
            app.ToolpathTab.Title = 'Toolpath';
            app.UIAxes = uiaxes(app.ToolpathTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartanimationButton = uibutton(app.ToolpathTab, 'push');
            app.StartanimationButton.ButtonPushedFcn = createCallbackFcn(app, @StartanimationButtonPushed, true);
            app.StartanimationButton.Text = 'Start animation';
            app.StopButton_2 = uibutton(app.ToolpathTab, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.GenerateButton_2 = uibutton(app.ToolpathTab, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_2Pushed, true);
            app.GenerateButton_2.Text = 'Generate';
            app.PassnoSpinnerLabel = uilabel(app.ToolpathTab);
            app.PassnoSpinnerLabel.Text = 'Pass no.';
            app.PassnoSpinner = uispinner(app.ToolpathTab);
            app.PassnoSpinner.Value = 1;
            app.ClearButton_2 = uibutton(app.ToolpathTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.AxesoffCheckBox_2 = uicheckbox(app.ToolpathTab);
            app.AxesoffCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AxesoffCheckBox_2ValueChanged, true);
            app.AxesoffCheckBox_2.Text = 'Axes off?';
            app.ShowborderCheckBox = uicheckbox(app.ToolpathTab);
            app.ShowborderCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowborderCheckBoxValueChanged, true);
            app.ShowborderCheckBox.Text = 'Show border?';
            app.ShownodesCheckBox = uicheckbox(app.ToolpathTab);
            app.ShownodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShownodesCheckBoxValueChanged, true);
            app.ShownodesCheckBox.Text = 'Show nodes?';
            app.SelectstrategyButtonGroup = uibuttongroup(app.GenerateprofileTab);
            app.SelectstrategyButtonGroup.Title = 'Select strategy';
            app.IndividualButton = uitogglebutton(app.SelectstrategyButtonGroup);
            app.IndividualButton.Text = 'Individual';
            app.IndividualButton.Value = true;
            app.StrategyButton = uitogglebutton(app.SelectstrategyButtonGroup);
            app.StrategyButton.Text = 'Strategy';
            app.StrategyDropDownLabel = uilabel(app.SelectstrategyButtonGroup);
            app.StrategyDropDownLabel.Text = 'Strategy';
            app.StrategyDropDown = uidropdown(app.SelectstrategyButtonGroup);
            app.StrategyDropDown.Items = {'Centre to outside', 'Converging', 'Overlayed', 'Track vertical', 'Track horizontal', 'Horizontal expand', 'Diagonal', 'Triangle', 'Vertical expand'};
            app.StrategyDropDown.Value = 'Centre to outside';
            app.CreateprofileButton = uibutton(app.SelectstrategyButtonGroup, 'push');
            app.CreateprofileButton.ButtonPushedFcn = createCallbackFcn(app, @CreateprofileButtonPushed, true);
            app.CreateprofileButton.Text = 'Create profile';
            app.ClearprofileButton = uibutton(app.SelectstrategyButtonGroup, 'push');
            app.ClearprofileButton.ButtonPushedFcn = createCallbackFcn(app, @ClearprofileButtonPushed, true);
            app.ClearprofileButton.Text = 'Clear profile';
            app.ExportdataPanel = uipanel(app.GenerateprofileTab);
            app.ExportdataPanel.Title = 'Export data';
            app.DirButton = uibutton(app.ExportdataPanel, 'push');
            app.DirButton.ButtonPushedFcn = createCallbackFcn(app, @DirButtonPushed, true);
            app.DirButton.Text = 'Dir';
            app.SelectdirectoryLabel = uilabel(app.ExportdataPanel);
            app.SelectdirectoryLabel.Text = 'Select directory:';
            app.ExportdatapathTextArea = uitextarea(app.ExportdataPanel);
            app.ExportoptionsDropDownLabel = uilabel(app.ExportdataPanel);
            app.ExportoptionsDropDownLabel.Text = 'Export options:';
            app.ExportoptionsDropDown = uidropdown(app.ExportdataPanel);
            app.ExportoptionsDropDown.Items = {'Strategies', 'Tool path plot', 'Sample density plot', 'Samples', 'Tool path plots', 'G-code', 'Tracking density plot', 'Tracking density'};
            app.ExportoptionsDropDown.Value = 'Strategies';
            app.ExportButton = uibutton(app.ExportdataPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.NameoffileEditFieldLabel = uilabel(app.ExportdataPanel);
            app.NameoffileEditFieldLabel.Text = 'Name of file:';
            app.NameoffileEditField = uieditfield(app.ExportdataPanel, 'text');
            app.ProcesssimulationTab = uitab(app.TabGroup2);
            app.ProcesssimulationTab.Title = 'Process simulation';
            app.UIAxes2 = uiaxes(app.ProcesssimulationTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes6 = uiaxes(app.ProcesssimulationTab);
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.CommandsPanel = uipanel(app.ProcesssimulationTab);
            app.CommandsPanel.Title = 'Commands';
            app.OperationnameTextAreaLabel = uilabel(app.CommandsPanel);
            app.OperationnameTextAreaLabel.Text = 'Operation name';
            app.OperationnameTextArea = uitextarea(app.CommandsPanel);
            app.GcodecommandTextAreaLabel = uilabel(app.CommandsPanel);
            app.GcodecommandTextAreaLabel.Text = 'G-code command';
            app.GcodecommandTextArea = uitextarea(app.CommandsPanel);
            app.SimulationparametersPanel = uipanel(app.ProcesssimulationTab);
            app.SimulationparametersPanel.Title = 'Simulation parameters';
            app.StartsimulationButton = uibutton(app.SimulationparametersPanel, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.PausePlayButton = uibutton(app.SimulationparametersPanel, 'push');
            app.PausePlayButton.ButtonPushedFcn = createCallbackFcn(app, @PausePlayButtonPushed, true);
            app.PausePlayButton.Text = 'Pause/Play';
            app.StopButton = uibutton(app.SimulationparametersPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PassnumberSpinnerLabel = uilabel(app.SimulationparametersPanel);
            app.PassnumberSpinnerLabel.Text = 'Pass number';
            app.PassnumberSpinner = uispinner(app.SimulationparametersPanel);
            app.PassnumberSpinner.Value = 2;
            app.AxesUITable = uitable(app.ProcesssimulationTab);
            app.AxesUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AxesUITable.RowName = {};
            app.AxespositionsLabel = uilabel(app.ProcesssimulationTab);
            app.AxespositionsLabel.Text = 'Axes positions';
            app.FeedratemmsTextAreaLabel = uilabel(app.ProcesssimulationTab);
            app.FeedratemmsTextAreaLabel.Text = 'Feed rate (mm/s)';
            app.FeedratemmsTextArea = uitextarea(app.ProcesssimulationTab);
            app.SamplegenerationTab = uitab(app.TabGroup2);
            app.SamplegenerationTab.Title = 'Sample generation';
            app.SamplingparametersPanel = uipanel(app.SamplegenerationTab);
            app.SamplingparametersPanel.Title = 'Sampling parameters';
            app.TabGroup7 = uitabgroup(app.SamplingparametersPanel);
            app.GeneralTab = uitab(app.TabGroup7);
            app.GeneralTab.Title = 'General';
            app.GridLayout22 = uigridlayout(app.GeneralTab);
            app.NumberofsamplesEditField = uieditfield(app.GridLayout22, 'numeric');
            app.NumberofsamplesEditField.Value = 4;
            app.NumberofsamplesEditFieldLabel = uilabel(app.GridLayout22);
            app.NumberofsamplesEditFieldLabel.Text = 'Number of samples';
            app.SamplingstrategyDropDown = uidropdown(app.GridLayout22);
            app.SamplingstrategyDropDown.Items = {'Centre to outside', 'Converging', 'Overlayed', 'Track vertical', 'Track horizontal', 'Horizontal expand', 'Diagonal', 'Triangle', 'Vertical expand'};
            app.SamplingstrategyDropDown.Value = 'Centre to outside';
            app.StrategyDropDown_2Label = uilabel(app.GridLayout22);
            app.StrategyDropDown_2Label.Text = 'Strategy';
            app.DimensionmagnitudeEditField = uieditfield(app.GridLayout22, 'numeric');
            app.DimensionmagnitudeEditField.Value = 20;
            app.DimensionmagnitudeLabel = uilabel(app.GridLayout22);
            app.DimensionmagnitudeLabel.Text = {'Dimension '; 'magnitude'};
            app.SamplingresolutionEditField = uieditfield(app.GridLayout22, 'numeric');
            app.SamplingresolutionEditField.Value = 10;
            app.ResolutionEditField_2Label = uilabel(app.GridLayout22);
            app.ResolutionEditField_2Label.Text = 'Resolution';
            app.SamplingcontinuousCheckBox = uicheckbox(app.GridLayout22);
            app.SamplingcontinuousCheckBox.ValueChangedFcn = createCallbackFcn(app, @SamplingcontinuousCheckBoxValueChanged, true);
            app.SamplingcontinuousCheckBox.Text = 'Continuous';
            app.SamplingdiscreteCheckBox = uicheckbox(app.GridLayout22);
            app.SamplingdiscreteCheckBox.ValueChangedFcn = createCallbackFcn(app, @SamplingdiscreteCheckBoxValueChanged, true);
            app.SamplingdiscreteCheckBox.Text = 'Discrete';
            app.MinimumtrackedareaEditFieldLabel = uilabel(app.GridLayout22);
            app.MinimumtrackedareaEditFieldLabel.Text = 'Minimum tracked area';
            app.MinimumtrackedareaEditField = uieditfield(app.GridLayout22, 'numeric');
            app.MinimumtrackedareaEditField.Value = 20;
            app.StrategyTab = uitab(app.TabGroup7);
            app.StrategyTab.Title = 'Strategy';
            app.TabGroup5 = uitabgroup(app.StrategyTab);
            app.CentretooutsideTab_2 = uitab(app.TabGroup5);
            app.CentretooutsideTab_2.Title = 'Centre to outside';
            app.GridLayout20 = uigridlayout(app.CentretooutsideTab_2);
            app.MinimumwidthEditField = uieditfield(app.GridLayout20, 'numeric');
            app.MinimumwidthEditField.Value = 50;
            app.MinimumwidthLabel = uilabel(app.GridLayout20);
            app.MinimumwidthLabel.Text = {'Minimum'; 'width'};
            app.MaximumwidthEditField = uieditfield(app.GridLayout20, 'numeric');
            app.MaximumwidthEditField.Value = 150;
            app.MaximumwidthEditFieldLabel_2 = uilabel(app.GridLayout20);
            app.MaximumwidthEditFieldLabel_2.Text = {'Maximum'; 'width'};
            app.MinimumheightEditField = uieditfield(app.GridLayout20, 'numeric');
            app.MinimumheightEditField.Value = 50;
            app.MinimumheightEditFieldLabel_3 = uilabel(app.GridLayout20);
            app.MinimumheightEditFieldLabel_3.Text = {'Minimum'; 'height'};
            app.MaximumheightEditField = uieditfield(app.GridLayout20, 'numeric');
            app.MaximumheightEditField.Value = 150;
            app.MaximumheightEditFieldLabel_2 = uilabel(app.GridLayout20);
            app.MaximumheightEditFieldLabel_2.Text = {'Maximum'; 'height'};
            app.MinimumxpositionEditField = uieditfield(app.GridLayout20, 'numeric');
            app.MinimumxpositionEditField.Value = 50;
            app.MinimumxpositionEditFieldLabel = uilabel(app.GridLayout20);
            app.MinimumxpositionEditFieldLabel.Text = {'Minimum'; 'x position'};
            app.MaximumxpositionEditField = uieditfield(app.GridLayout20, 'numeric');
            app.MaximumxpositionEditField.Value = 250;
            app.MaximumxpositionEditFieldLabel = uilabel(app.GridLayout20);
            app.MaximumxpositionEditFieldLabel.Text = {'Maximum'; 'x position'};
            app.MinimumypositionEditField = uieditfield(app.GridLayout20, 'numeric');
            app.MinimumypositionEditField.Value = 50;
            app.MinimumypositionEditFieldLabel = uilabel(app.GridLayout20);
            app.MinimumypositionEditFieldLabel.Text = {'Minimum'; 'y position'};
            app.MaximumypositionEditField = uieditfield(app.GridLayout20, 'numeric');
            app.MaximumypositionEditField.Value = 250;
            app.MaximumypositionEditFieldLabel = uilabel(app.GridLayout20);
            app.MaximumypositionEditFieldLabel.Text = {'Maximum'; 'y position'};
            app.MinimumscalingEditField = uieditfield(app.GridLayout20, 'numeric');
            app.MinimumscalingEditField.Value = 3;
            app.MinimumscalingEditFieldLabel = uilabel(app.GridLayout20);
            app.MinimumscalingEditFieldLabel.Text = {'Minimum'; 'scaling'};
            app.MaximumscalingEditField = uieditfield(app.GridLayout20, 'numeric');
            app.MaximumscalingEditField.Value = 5;
            app.MaximumscalingEditFieldLabel = uilabel(app.GridLayout20);
            app.MaximumscalingEditFieldLabel.Text = {'Maximum'; 'scaling'};
            app.DiagonalTab_2 = uitab(app.TabGroup5);
            app.DiagonalTab_2.Title = 'Diagonal';
            app.VerticalexpansionTab = uitab(app.TabGroup5);
            app.VerticalexpansionTab.Title = 'Vertical expansion';
            app.MinimumwidthEditField_2Label_2 = uilabel(app.VerticalexpansionTab);
            app.MinimumwidthEditField_2Label_2.Text = {'Minimum'; 'width'};
            app.MinimumwidthEditField_2 = uieditfield(app.VerticalexpansionTab, 'numeric');
            app.MinimumwidthEditField_2.Value = 50;
            app.MaximumwidthEditField_2Label = uilabel(app.VerticalexpansionTab);
            app.MaximumwidthEditField_2Label.Text = {'Maximum'; 'width'};
            app.MaximumwidthEditField_2 = uieditfield(app.VerticalexpansionTab, 'numeric');
            app.MaximumwidthEditField_2.Value = 150;
            app.MinimumxpositionEditField_2Label = uilabel(app.VerticalexpansionTab);
            app.MinimumxpositionEditField_2Label.Text = {'Minimum'; 'x position'};
            app.MinimumxpositionEditField_2 = uieditfield(app.VerticalexpansionTab, 'numeric');
            app.MinimumxpositionEditField_2.Value = 50;
            app.MaximumxpositionEditField_2Label = uilabel(app.VerticalexpansionTab);
            app.MaximumxpositionEditField_2Label.Text = {'Maximum'; 'x position'};
            app.MaximumxpositionEditField_2 = uieditfield(app.VerticalexpansionTab, 'numeric');
            app.MaximumxpositionEditField_2.Value = 250;
            app.MinimumscalingEditField_2Label = uilabel(app.VerticalexpansionTab);
            app.MinimumscalingEditField_2Label.Text = {'Minimum'; 'scaling'};
            app.MinimumscalingEditField_2 = uieditfield(app.VerticalexpansionTab, 'numeric');
            app.MinimumscalingEditField_2.Value = 3;
            app.MaximumscalingEditField_2Label = uilabel(app.VerticalexpansionTab);
            app.MaximumscalingEditField_2Label.Text = {'Maximum'; 'scaling'};
            app.MaximumscalingEditField_2 = uieditfield(app.VerticalexpansionTab, 'numeric');
            app.MaximumscalingEditField_2.Value = 10;
            app.HorizontalexpansionTab = uitab(app.TabGroup5);
            app.HorizontalexpansionTab.Title = 'Horizontal expansion';
            app.MinimumheightEditField_2Label_2 = uilabel(app.HorizontalexpansionTab);
            app.MinimumheightEditField_2Label_2.Text = {'Minimum'; 'height'};
            app.MinimumheightEditField_2 = uieditfield(app.HorizontalexpansionTab, 'numeric');
            app.MinimumheightEditField_2.Value = 50;
            app.MaximumheightEditField_2Label = uilabel(app.HorizontalexpansionTab);
            app.MaximumheightEditField_2Label.Text = {'Maximum'; 'height'};
            app.MaximumheightEditField_2 = uieditfield(app.HorizontalexpansionTab, 'numeric');
            app.MaximumheightEditField_2.Value = 150;
            app.MinimumypositionEditField_2Label = uilabel(app.HorizontalexpansionTab);
            app.MinimumypositionEditField_2Label.Text = {'Minimum'; 'y position'};
            app.MinimumypositionEditField_2 = uieditfield(app.HorizontalexpansionTab, 'numeric');
            app.MinimumypositionEditField_2.Value = 50;
            app.MaximumypositionEditField_2Label = uilabel(app.HorizontalexpansionTab);
            app.MaximumypositionEditField_2Label.Text = {'Maximum'; 'y position'};
            app.MaximumypositionEditField_2 = uieditfield(app.HorizontalexpansionTab, 'numeric');
            app.MaximumypositionEditField_2.Value = 250;
            app.MinimumscalingEditField_3Label = uilabel(app.HorizontalexpansionTab);
            app.MinimumscalingEditField_3Label.Text = {'Minimum'; 'scaling'};
            app.MinimumscalingEditField_3 = uieditfield(app.HorizontalexpansionTab, 'numeric');
            app.MinimumscalingEditField_3.Value = 3;
            app.MaximumscalingEditField_3Label = uilabel(app.HorizontalexpansionTab);
            app.MaximumscalingEditField_3Label.Text = {'Maximum'; 'scaling'};
            app.MaximumscalingEditField_3 = uieditfield(app.HorizontalexpansionTab, 'numeric');
            app.MaximumscalingEditField_3.Value = 10;
            app.OverlayedTab_2 = uitab(app.TabGroup5);
            app.OverlayedTab_2.Title = 'Overlayed';
            app.MaximumwidthEditField_3Label = uilabel(app.OverlayedTab_2);
            app.MaximumwidthEditField_3Label.Text = {'Maximum'; 'width'};
            app.MaximumwidthEditField_3 = uieditfield(app.OverlayedTab_2, 'numeric');
            app.MaximumwidthEditField_3.Value = 400;
            app.MinimumxpositionEditField_3Label = uilabel(app.OverlayedTab_2);
            app.MinimumxpositionEditField_3Label.Text = {'Minimum'; 'x position'};
            app.MinimumxpositionEditField_3 = uieditfield(app.OverlayedTab_2, 'numeric');
            app.MinimumxpositionEditField_3.Value = 50;
            app.MaximumxpositionEditField_3Label = uilabel(app.OverlayedTab_2);
            app.MaximumxpositionEditField_3Label.Text = {'Maximum'; 'x position'};
            app.MaximumxpositionEditField_3 = uieditfield(app.OverlayedTab_2, 'numeric');
            app.MaximumxpositionEditField_3.Value = 250;
            app.MinimumypositionEditField_3Label = uilabel(app.OverlayedTab_2);
            app.MinimumypositionEditField_3Label.Text = {'Minimum'; 'y position'};
            app.MinimumypositionEditField_3 = uieditfield(app.OverlayedTab_2, 'numeric');
            app.MinimumypositionEditField_3.Value = 50;
            app.MaximumypositionEditField_3Label = uilabel(app.OverlayedTab_2);
            app.MaximumypositionEditField_3Label.Text = {'Maximum'; 'y position'};
            app.MaximumypositionEditField_3 = uieditfield(app.OverlayedTab_2, 'numeric');
            app.MaximumypositionEditField_3.Value = 250;
            app.MinimumheightEditField_3Label = uilabel(app.OverlayedTab_2);
            app.MinimumheightEditField_3Label.Text = {'Minimum'; 'height'};
            app.MinimumheightEditField_3 = uieditfield(app.OverlayedTab_2, 'numeric');
            app.MinimumheightEditField_3.Value = 50;
            app.MaximumheightEditField_3Label = uilabel(app.OverlayedTab_2);
            app.MaximumheightEditField_3Label.Text = {'Maximum'; 'height'};
            app.MaximumheightEditField_3 = uieditfield(app.OverlayedTab_2, 'numeric');
            app.MaximumheightEditField_3.Value = 400;
            app.MinimumwidthEditField_3Label = uilabel(app.OverlayedTab_2);
            app.MinimumwidthEditField_3Label.Text = {'Minimum'; 'width'};
            app.MinimumwidthEditField_3 = uieditfield(app.OverlayedTab_2, 'numeric');
            app.MinimumwidthEditField_3.Value = 50;
            app.TriangularTab = uitab(app.TabGroup5);
            app.TriangularTab.Title = 'Triangular';
            app.MinimumbasewidthEditFieldLabel = uilabel(app.TriangularTab);
            app.MinimumbasewidthEditFieldLabel.Text = {'Minimum'; 'base width'};
            app.MinimumbasewidthEditField = uieditfield(app.TriangularTab, 'numeric');
            app.MinimumbasewidthEditField.Value = 100;
            app.MaximumbasewidthEditFieldLabel = uilabel(app.TriangularTab);
            app.MaximumbasewidthEditFieldLabel.Text = {'Maximum'; 'base width'};
            app.MaximumbasewidthEditField = uieditfield(app.TriangularTab, 'numeric');
            app.MaximumbasewidthEditField.Value = 400;
            app.MinimumxpositionEditField_4Label = uilabel(app.TriangularTab);
            app.MinimumxpositionEditField_4Label.Text = {'Minimum'; 'x position'};
            app.MinimumxpositionEditField_4 = uieditfield(app.TriangularTab, 'numeric');
            app.MinimumxpositionEditField_4.Value = 50;
            app.MaximumxpositionEditField_4Label = uilabel(app.TriangularTab);
            app.MaximumxpositionEditField_4Label.Text = {'Maximum'; 'x position'};
            app.MaximumxpositionEditField_4 = uieditfield(app.TriangularTab, 'numeric');
            app.MaximumxpositionEditField_4.Value = 250;
            app.MinimumypositionEditField_4Label = uilabel(app.TriangularTab);
            app.MinimumypositionEditField_4Label.Text = {'Minimum'; 'y position'};
            app.MinimumypositionEditField_4 = uieditfield(app.TriangularTab, 'numeric');
            app.MinimumypositionEditField_4.Value = 50;
            app.MaximumypositionEditField_4Label = uilabel(app.TriangularTab);
            app.MaximumypositionEditField_4Label.Text = {'Maximum'; 'y position'};
            app.MaximumypositionEditField_4 = uieditfield(app.TriangularTab, 'numeric');
            app.MaximumypositionEditField_4.Value = 250;
            app.MinimumheightEditField_4Label = uilabel(app.TriangularTab);
            app.MinimumheightEditField_4Label.Text = {'Minimum'; 'height'};
            app.MinimumheightEditField_4 = uieditfield(app.TriangularTab, 'numeric');
            app.MinimumheightEditField_4.Value = 100;
            app.MaximumheightEditField_4Label = uilabel(app.TriangularTab);
            app.MaximumheightEditField_4Label.Text = {'Maximum'; 'height'};
            app.MaximumheightEditField_4 = uieditfield(app.TriangularTab, 'numeric');
            app.MaximumheightEditField_4.Value = 400;
            app.DataPanel = uipanel(app.SamplegenerationTab);
            app.DataPanel.Title = 'Data';
            app.TabGroup6 = uitabgroup(app.DataPanel);
            app.SamplespaceTab = uitab(app.TabGroup6);
            app.SamplespaceTab.Title = 'Sample space';
            app.SamplespaceUITable = uitable(app.SamplespaceTab);
            app.SamplespaceUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SamplespaceUITable.RowName = {};
            app.SelectionTab = uitab(app.TabGroup6);
            app.SelectionTab.Title = 'Selection';
            app.SampleselectionUITable = uitable(app.SelectionTab);
            app.SampleselectionUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SampleselectionUITable.RowName = {};
            app.AreavariationTab = uitab(app.TabGroup6);
            app.AreavariationTab.Title = 'Area variation';
            app.AreaUIAxes = uiaxes(app.AreavariationTab);
            xlabel(app.AreaUIAxes, 'X')
            ylabel(app.AreaUIAxes, 'Y')
            zlabel(app.AreaUIAxes, 'Z')
            app.GenerateButton = uibutton(app.AreavariationTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ParametervariationTab = uitab(app.TabGroup6);
            app.ParametervariationTab.Title = 'Parameter variation';
            app.ParamUIAxes = uiaxes(app.ParametervariationTab);
            xlabel(app.ParamUIAxes, 'X')
            ylabel(app.ParamUIAxes, 'Y')
            zlabel(app.ParamUIAxes, 'Z')
            app.SelectparameterDropDownLabel = uilabel(app.ParametervariationTab);
            app.SelectparameterDropDownLabel.Text = 'Select parameter';
            app.SelectparameterDropDown = uidropdown(app.ParametervariationTab);
            app.SelectparameterDropDown.Items = {'1', '2', '3', '4', '5'};
            app.SelectparameterDropDown.Value = '1';
            app.DisplayButton = uibutton(app.ParametervariationTab, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.VisualisePanel = uipanel(app.SamplegenerationTab);
            app.VisualisePanel.Title = 'Visualise';
            app.SamplevisualiseUIAxes = uiaxes(app.VisualisePanel);
            xlabel(app.SamplevisualiseUIAxes, 'X')
            ylabel(app.SamplevisualiseUIAxes, 'Y')
            zlabel(app.SamplevisualiseUIAxes, 'Z')
            app.Panel = uipanel(app.SamplegenerationTab);
            app.TabGroup8 = uitabgroup(app.Panel);
            app.GenerateTab = uitab(app.TabGroup8);
            app.GenerateTab.Title = 'Generate';
            app.GeneratesamplesButton = uibutton(app.GenerateTab, 'push');
            app.GeneratesamplesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratesamplesButtonPushed, true);
            app.GeneratesamplesButton.Text = 'Generate samples';
            app.ClearButton = uibutton(app.GenerateTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.VisualiseButton = uibutton(app.GenerateTab, 'push');
            app.VisualiseButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButtonPushed, true);
            app.VisualiseButton.Text = 'Visualise';
            app.SamplenumberSpinnerLabel = uilabel(app.GenerateTab);
            app.SamplenumberSpinnerLabel.Text = 'Sample number';
            app.SamplenumberSpinner = uispinner(app.GenerateTab);
            app.SamplenumberSpinner.Value = 2;
            app.ExportTab = uitab(app.TabGroup8);
            app.ExportTab.Title = 'Export';
            app.DirButton_2 = uibutton(app.ExportTab, 'push');
            app.DirButton_2.ButtonPushedFcn = createCallbackFcn(app, @DirButton_2Pushed, true);
            app.DirButton_2.Text = 'Dir';
            app.SelectdirectoryLabel_2 = uilabel(app.ExportTab);
            app.SelectdirectoryLabel_2.Text = {'Select '; 'directory:'};
            app.ExportdatapathTextArea_2 = uitextarea(app.ExportTab);
            app.NameoffileEditField_2Label = uilabel(app.ExportTab);
            app.NameoffileEditField_2Label.Text = 'Name of file:';
            app.NameoffileEditField_2 = uieditfield(app.ExportTab, 'text');
            app.ExportoptionsDropDown_2Label = uilabel(app.ExportTab);
            app.ExportoptionsDropDown_2Label.Text = 'Export options:';
            app.ExportoptionsDropDown_2 = uidropdown(app.ExportTab);
            app.ExportoptionsDropDown_2.Items = {'Strategies', 'Parameter variation plot', 'Area variation plot', 'Matlab figure', 'Samples', 'Tool path plots', 'G-code', 'Tracking density plot', 'Tracking density'};
            app.ExportoptionsDropDown_2.Value = 'Strategies';
            app.ExportButton_2 = uibutton(app.ExportTab, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WheelingProfileVisual

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end